classdef tutorial_edit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadandSavePanel                matlab.ui.container.Panel
        SaveParamsFilewithcurrentconfigurationButton  matlab.ui.control.Button
        SaveNetworkandQuitButton        matlab.ui.control.Button
        CurrentConfigFileEditField      matlab.ui.control.EditField
        CurrentConfigFileEditFieldLabel  matlab.ui.control.Label
        SelectNetworkConfigFileButton   matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        NetworkTab                      matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        ActionPotentialsTab             matlab.ui.container.Tab
        SaveplotButton                  matlab.ui.control.Button
        ImportDataButton                matlab.ui.control.Button
        CellMLFileDataEditField         matlab.ui.control.EditField
        CellMLFileDataEditFieldLabel    matlab.ui.control.Label
        ImportCellMLDataCheckBox        matlab.ui.control.CheckBox
        PulseandPlotPanel               matlab.ui.container.Panel
        PlotAPButton                    matlab.ui.control.Button
        PulsePeriodEditField            matlab.ui.control.NumericEditField
        PulsePeriodEditFieldLabel       matlab.ui.control.Label
        ExternalPulseCheckBox           matlab.ui.control.CheckBox
        UIAxes2                         matlab.ui.control.UIAxes
        RestitutionCurvesTab            matlab.ui.container.Tab
        UseExternaldataCheckBox         matlab.ui.control.CheckBox
        ImportExternaldataButton        matlab.ui.control.Button
        SaveRestitutionPlotButton       matlab.ui.control.Button
        PlotRestitutionCurveButton      matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        NodesTab                        matlab.ui.container.Tab
        EditNodePanel                   matlab.ui.container.Panel
        NodeTypeEditField               matlab.ui.control.EditField
        NodeTypeEditFieldLabel          matlab.ui.control.Label
        NodeNumberEditField             matlab.ui.control.EditField
        NodeNumberEditFieldLabel        matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        AttributeValueEditField         matlab.ui.control.NumericEditField
        AttributeValueEditFieldLabel    matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.Button
        AttributeDropDown               matlab.ui.control.DropDown
        AttributeDropDownLabel          matlab.ui.control.Label
        SelectNodeButton_2              matlab.ui.control.Button
        NodeDeletionPanel               matlab.ui.container.Panel
        DeleteNodeButton                matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        SelectNodeButton                matlab.ui.control.Button
        NodeCreationPanel               matlab.ui.container.Panel
        NodeNameEditField               matlab.ui.control.EditField
        NodeNameEditFieldLabel          matlab.ui.control.Label
        CancelButton_2                  matlab.ui.control.Button
        SaveNodeButton                  matlab.ui.control.Button
        NodePresetsDropDown             matlab.ui.control.DropDown
        NodePresetsDropDownLabel        matlab.ui.control.Label
        NodeTypeDropDown                matlab.ui.control.DropDown
        NodeTypeDropDownLabel           matlab.ui.control.Label
        SelectLocationButton            matlab.ui.control.Button
        PathsTab                        matlab.ui.container.Tab
        PathCreationPanel               matlab.ui.container.Panel
        PathPresetDropDown              matlab.ui.control.DropDown
        PathPresetDropDownLabel         matlab.ui.control.Label
        CancelPathCreationButton        matlab.ui.control.Button
        SavePathButton                  matlab.ui.control.Button
        SelectNode2Button               matlab.ui.control.Button
        SelectNode1Button               matlab.ui.control.Button
        Node2jEditField_2               matlab.ui.control.EditField
        Node2jLabel_2                   matlab.ui.control.Label
        Node1iEditField_2               matlab.ui.control.EditField
        Node1iLabel_2                   matlab.ui.control.Label
        PathDeletionPanel               matlab.ui.container.Panel
        CancelPathDeletionButton        matlab.ui.control.Button
        DeletePathButton                matlab.ui.control.Button
        SelectPathButton                matlab.ui.control.Button
        EditPathPanel                   matlab.ui.container.Panel
        RDelaymsEditField               matlab.ui.control.NumericEditField
        RDelaymsEditFieldLabel          matlab.ui.control.Label
        ADelaymsEditField               matlab.ui.control.NumericEditField
        ADelaymsEditFieldLabel          matlab.ui.control.Label
        ResetButton_2                   matlab.ui.control.Button
        AttributeValueEditField_2       matlab.ui.control.NumericEditField
        AttributeValueEditField_2Label  matlab.ui.control.Label
        AttributeDropDown_2             matlab.ui.control.DropDown
        AttributeDropDown_2Label        matlab.ui.control.Label
        UpdateButton_2                  matlab.ui.control.Button
        SelectPathButton_2              matlab.ui.control.Button
        Node2jEditField                 matlab.ui.control.EditField
        Node2jLabel                     matlab.ui.control.Label
        Node1iEditField                 matlab.ui.control.EditField
        Node1iLabel                     matlab.ui.control.Label
        ProbesTab_2                     matlab.ui.container.Tab
        EditProbePanel                  matlab.ui.container.Panel
        ProbeTypeEditField              matlab.ui.control.EditField
        ProbeTypeEditFieldLabel         matlab.ui.control.Label
        ProbeNumberEditField            matlab.ui.control.EditField
        ProbeNumberEditFieldLabel       matlab.ui.control.Label
        ResetButton_3                   matlab.ui.control.Button
        UpdateButton_3                  matlab.ui.control.Button
        ParameterValueEditField         matlab.ui.control.NumericEditField
        ParameterValueEditFieldLabel    matlab.ui.control.Label
        ParameterDropDown               matlab.ui.control.DropDown
        ParameterLabel                  matlab.ui.control.Label
        SelectProbeButton_2             matlab.ui.control.Button
        ProbeDeletionPanel              matlab.ui.container.Panel
        DeleteProbeButton               matlab.ui.control.Button
        CancelButton_4                  matlab.ui.control.Button
        SelectProbeButton               matlab.ui.control.Button
        ProbeCreationPanel              matlab.ui.container.Panel
        SelectEndLocationButton         matlab.ui.control.Button
        ZPositionEditField              matlab.ui.control.NumericEditField
        ZPositionEditFieldLabel         matlab.ui.control.Label
        CancelButton_3                  matlab.ui.control.Button
        SaveProbeButton                 matlab.ui.control.Button
        ProbeTypeDropDown               matlab.ui.control.DropDown
        ProbeTypeDropDownLabel          matlab.ui.control.Label
        SelectStartLocationButton       matlab.ui.control.Button
        ColouringTab                    matlab.ui.container.Tab
        ResetPathColoursButton          matlab.ui.control.Button
        ResetNodeColoursButton          matlab.ui.control.Button
        ColourPathsButton               matlab.ui.control.Button
        ColourNodesButton               matlab.ui.control.Button
        PathAttributeDropDown           matlab.ui.control.DropDown
        PathAttributeDropDownLabel      matlab.ui.control.Label
        NodeAttributeDropDown           matlab.ui.control.DropDown
        NodeAttributeDropDownLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        newnodebool % Boolean for creating a node
        delnodebool % Boolean for deleting a node
        editnodebool % Boolean for editing a node
        tooltipObj % object that corresponds to the node tooltip
        editnodeind % index for node to edit
        delnodeind % index for node to delete
        node1ind % index for path creation node i
        node2ind % index for path creation node j
        node1bool % Boolean for selecting node j for path creation
        node2bool % Boolean for selecting node j for path creation
        nodeTypesAttr % attributes which correspond to each unique cell type
        Node_pos % The coordinates of the nodes loaded in
        node_connections % Linkages for node arrays
        path_plot % physical lines corresponding to the paths in the model
        path_connections % linkages for path arrays
        node_pos_plot % scatter plot for the nodes
        probe_pos_plot % scatter plot for the probes
        Probe_pos % The coordinates for the probes loaded in
        raw_nodes % node attributes the full network things
        raw_nodes_reserve % reserve for going to original settings
        raw_paths % path attributes the full network things
        raw_paths_reserve % reserve for going to original settings
        raw_probes % probe attributes for the network
        raw_probes_reserve % reserve for going to original settings
        node_presets % node presets for creating a new node
        path_presets % path presets for creating a new path
        unique_node_idx % indexing corresponding with the node presets
        unique_path_idx % indexing corresponding with the path presets
        editpathind % edit path index
        delpathind % delete path index
        delpathbool % delete path boolean
        editpathbool % edit path boolean
        firsttime_path
        path_ind_visual
        node_text % check to solve
        unique_node_presets % front end structure for node presets
        node_preset_dict % Back end dictionary for node presets
        path_preset_dict % Back end dictionary for path presets
        ogtrace % Original AP node trace
        newtrace % Updated AP node trace
        VTidx % Index for the current nodes threshold voltage
        delprobebool
        editprobebool
        newprobebool
        probe2bool
        editprobeind % index for probe editing
        delprobeind % index for probe deletion
        probe_connections
        cellML_time % array for time from a cellML model
        cellML_voltage % array for voltage from a cellML model
        cellML_delay % stimulation delay for the cellML data
        node_lookup
        path_lookup
        ms_or_not
    end    
    
    methods (Access = private)
        
                
        function d = point_to_line(app, pt, v1, v2)
            % TO ADD: 3D capability functionality (shorten it)
            % Euclidean distance to the midpoint of the line  
            midpoint = [0.5*(v1(1) +v2(1)) 0.5*(v1(2)+v2(2))];
            %midpoint = [0.5*(v1(1) +v2(1)) 0.5*(v1(2)+v2(2)) 0.5*(v1(3)+v2(3))];
            d = pdist([pt;midpoint],'euclidean');
        end
        
        function index = nearest_line(app,pt)
            minimum_val = 1000000;
            if size(app.raw_paths,1) == 0 
                index = 0;
                return
            end
            for i = 2:size(app.raw_paths,1)
                [node1idx,~] = find(app.node_connections == app.raw_paths{i,3});
                [node2idx,~] = find(app.node_connections == app.raw_paths{i,4});

                %curr_dist = app.point_to_line(pt,app.Node_pos(node1idx,1:3),app.Node_pos(node2idx,1:3));
                curr_dist = app.point_to_line(pt,app.Node_pos(node1idx,1:2),app.Node_pos(node2idx,1:2));
                if curr_dist < minimum_val
                    minimum_val = curr_dist;
                    index = i-1;        
                end
            end
        end
        
        function val = unique_num(app,array)
            B = sort(array);
            for i=1:length(B)
                if i ~= B(i)
                    val = i;
                    return
                end
            end
            val = B(end)+1;
        end
        
        function event_handle(app,event,a0,a1,a2,a3,a4,a5,a6,a7,a8,a9,a10)
            % Dummy function to pass clicking the plot elements into the
            % axes button down function
            app.UIAxesButtonDown(event)
        end

        function probe_reset(app,idx)

            x_temp = get(app.probe_pos_plot,'XData');
            y_temp = get(app.probe_pos_plot,'YData');
            c = get(app.probe_pos_plot,'CData');
            if idx == 3
                x_temp(app.editprobeind) = app.raw_probes{app.editprobeind+1,idx};
            elseif idx == 4
                y_temp(app.editprobeind) = app.raw_probes{app.editprobeind+1,idx};
            end
            set(app.probe_pos_plot,'XData',x_temp,'YData',y_temp,'CData',c);
        end

        function xlsx_load(app,f)          
            %popup box for the user defined inputs
            prompt = {'Node Sheet Name:','Path Sheet Name:', 'Probe Sheet Name:'};
            dlgtitle = 'Input';
            fieldsize = [1 30; 1 30; 1 30];
            definput = {'Nodes','Paths', 'Probes'};
            answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
            
            [~,~,app.raw_nodes]=xlsread(f,answer{1});
            [~,~,app.raw_paths]=xlsread(f,answer{2});
            [~,~,app.raw_probes]=xlsread(f,answer{3});
            %app.raw_nodes(:,end-2) = [];
            %app.raw_nodes(end,:) = [];
        end

        function paths_setup(app)
            exact_match = strcmp(app.raw_nodes(1,:), 'x');
            x_idx = find(exact_match);
            exact_match = strcmp(app.raw_nodes(1,:), 'y');
            y_idx = find(exact_match);
            
            for i=2:size(app.raw_paths,1)
                
                % node1idx = find(app.raw_nodes{:,3} == app.raw_paths{i,3});
                % node2idx = find(app.raw_nodes{:,3} == app.raw_paths{i,4});
                node2idx = app.raw_paths{i,4}+1;
                node1idx = app.raw_paths{i,3}+1;
                app.raw_paths{i,16} = app.raw_nodes{node1idx,x_idx};% node1 x
                app.raw_paths{i,17} = app.raw_nodes{node1idx,y_idx};% node1 y

                app.raw_paths{i,18} = app.raw_nodes{node2idx,x_idx};%node2 x
                app.raw_paths{i,19} = app.raw_nodes{node2idx,y_idx};% node2 y
                app.raw_paths{i,20} = sqrt((app.raw_paths{i,16} -app.raw_paths{i,18})^2 + (app.raw_paths{i,17} -app.raw_paths{i,19})^2);
                app.raw_paths{i,22} = app.raw_paths{i,20}/app.raw_paths{i,10};% RDelay
                app.raw_paths{i,21} = app.raw_paths{i,20}/app.raw_paths{i,5}; % ADelay
                
                
            end
        end

        function mat_load(app,f)
            dim=load(f);

            paths_start = {'Starti', 'Endj', 'Indexi', 'Indexj'};
            nodes_start = {'Node_name', 'Type', 'Index'};
            nodes_end = cat(1,{'APD90', 'ERP'},cell(size(dim.Node_Raw,1),2));
            paths_end = cat(1,{'x', 'y', 'x','y','Distance','A-Delay(ms)','R-Delay(ms)','Path','A-Delay(ms)','R-Delay(ms)'},cell(size(dim.Path_Raw,1),10));
            app.raw_nodes = cat(2,cat(1,cat(2,nodes_start,dim.Node_para),dim.Node_Raw),nodes_end);
            app.raw_paths = cat(2,cat(1,cat(2,paths_start,dim.Path_para),dim.Path_Raw),paths_end);
            app.paths_setup()
            app.raw_probes = dim.Probe_Raw; 
            app.node_lookup = dim.Node_lookup;
            app.path_lookup = dim.Path_lookup;
            app.SaveParamsFilewithcurrentconfigurationButton.Enable = "on";
            
            
        end
        function load_cellMLfile(app,filePath,fileName)


            raw = readtable(fullfile(filePath,fileName),VariableNamingRule="preserve");
            app.CellMLFileDataEditField.Value = fileName;
            naming = raw.Properties.VariableNames;
            % Obtain the time index data
            time_idx = find(contains(naming,'environment'));
            voltage_patterns = "membrane | v (millivolt)";
            voltage_idx = find(contains(naming,voltage_patterns,'IgnoreCase',true));

            stim = find(contains(naming,"membrane | stim_start (millisecond)",'IgnoreCase',true));
            if isempty(stim)
                assignin('base',"phase_delay",0)
                app.cellML_delay = 0;
            else
                app.cellML_delay = raw{3,stim};
                assignin('base',"phase_delay",app.cellML_delay)
            end
            app.cellML_time = raw{:,time_idx};
            app.cellML_voltage = raw{:,voltage_idx};
            app.cellML_voltage = app.cellML_voltage - min(app.cellML_voltage);
        end

        function load_cfgfile(app,filePath,fileName)
            f = fullfile(filePath,fileName);
            app.CurrentConfigFileEditField.Value = fileName;           
            % prompt = {'Unit selection:'};
            % definput = {'Seconds', 'Milliseconds'};
            % [answer, tf] = listdlg('PromptString',prompt,'SelectionMode','single','ListString',definput);
            answer = questdlg('Which units is this CFG file in?', ...
	            'Unit Selection', ...
	            'Milliseconds','Seconds','Seconds');
            % Handle response
            switch answer
                case 'Milliseconds'
                    app.ms_or_not = true;
                case 'Seconds'
                    app.ms_or_not = false;
            end
            
            app.reset();
            if strcmp(answer(1),'Seconds')
                assignin('base','solvertime',10);
                assignin('base','stepsize',0.0005);
                assignin('base','buffer',0.599);
                assignin('base','unit_conversion',1);
                assignin('base','timescale','s');
                assignin('base','restEnd',30);
                assignin('base','trim',0.01);
                assignin('base','duration',0.003);
                assignin('base','initial',0.7);
                xlabel(app.UIAxes2, 'Time (s)')
                ylabel(app.UIAxes2, 'Membrane Potential (mV)')
                xlabel(app.UIAxes3, 'DI (s)')
                ylabel(app.UIAxes3, 'APD (s)')
            else
                assignin('base','solvertime',5000);
                assignin('base','stepsize',1);
                assignin('base','buffer',599);
                assignin('base','unit_conversion',1000);
                assignin('base','timescale','ms');
                assignin('base','restEnd',30000);
                assignin('base','trim',10);
                assignin('base','duration',3);
                assignin('base','initial',700);
                xlabel(app.UIAxes2, 'Time (ms)')
                ylabel(app.UIAxes2, 'Membrane Potential (mV)')
                xlabel(app.UIAxes3, 'DI (ms)')
                ylabel(app.UIAxes3, 'APD (ms)')
            end
            if strcmp(fileName(end-3:end),'.mat')
                app.mat_load(f)
            else
                app.xlsx_load(f)
            end

            app.raw_nodes_reserve = app.raw_nodes;
            app.raw_paths_reserve = app.raw_paths;
            app.raw_probes_reserve = app.raw_probes;
            app.nodeTypesAttr = dictionary(["N", "M", "NM"],{app.raw_nodes(1,4:24),app.raw_nodes(1,25:47),app.raw_nodes(1,4:47)});
            app.AttributeDropDown_2.Items = app.raw_paths(1,5:15);
            app.PathAttributeDropDown.Items = app.raw_paths(1,[5:15,21:22]); 

            % Node presets based on the unique existing nodes in the loaded
            % network file
            % HERE: this loading in (and similar with the startup fcn)
            % assumes only a 2D network
            node_temp = cell2mat(app.raw_nodes(2:end,4:end));         
            node_temp(:,45:46)=40000;
            node_temp(isnan(node_temp))=40000;
            [app.node_presets,app.unique_node_idx] = unique(node_temp,'rows');
            app.node_presets = changem(app.node_presets,NaN,40000);
            app.unique_node_idx = app.unique_node_idx +1;
            % Dictionary for the drop down menu
            app.unique_node_presets = struct('N', [], 'M', [], 'NM', []);
            % Back end dictionary for saving the correct preset
            app.node_preset_dict = dictionary;
            for idx = 1:length(app.unique_node_idx)
                curr =app.raw_nodes_reserve{app.unique_node_idx(idx),1};
                if strcmp(app.raw_nodes_reserve{app.unique_node_idx(idx),2},"N")
                    app.unique_node_presets.N{end+1} = curr;
                elseif strcmp(app.raw_nodes_reserve{app.unique_node_idx(idx),2},"M")
                    app.unique_node_presets.M{end+1} = curr;
                elseif strcmp(app.raw_nodes_reserve{app.unique_node_idx(idx),2},"NM")              
                    app.unique_node_presets.NM{end+1} = curr;
                end
                app.node_preset_dict(curr) = {app.node_presets(idx,:)};
            end

            app.NodePresetsDropDown.Items = app.unique_node_presets.N;
            app.NodeAttributeDropDown.Items = app.nodeTypesAttr{"NM"};

            % Path presets based on the unique existing nodes in the loaded
            % network file

            path_temp = cell2mat(app.raw_paths_reserve(2:end,5:15));
            [app.path_presets,app.unique_path_idx] = unique(path_temp,'rows');
            app.unique_path_idx = app.unique_path_idx +1;
            app.path_preset_dict = dictionary;

            strings = cell(length(app.unique_path_idx),1);
            for idx = 1:length(app.unique_path_idx)
                strings{idx} = append(app.raw_paths_reserve{app.unique_path_idx(idx),1},' ','-',' ',...
                    app.raw_paths_reserve{app.unique_path_idx(idx),2});
                app.path_preset_dict(strings{idx}) = {app.path_presets(idx,:)};
            end
            app.PathPresetDropDown.Items = strings;

            NumPaths = size(cell2mat(app.raw_paths(2:end,4)),1);
            NumNodes = size(cell2mat(app.raw_nodes(2:end,4)),1);
            app.Node_pos= cell2mat(app.raw_nodes(2:end,end-3:end-2));
            app.Probe_pos= cell2mat(app.raw_probes(2:end,3:4));
            app.UIAxes.XLim = [round(min(app.Node_pos(:,1)),-1)-10 round(max(app.Node_pos(:,1)),-1)+10];
            app.UIAxes.YLim = [round(min(app.Node_pos(:,2)),-1)-10 round(max(app.Node_pos(:,2)),-1)+10];
            app.node_text = [];
            % Display the nodes and probes; Store the node and probe positions
            if ~isempty(app.Node_pos)
                app.node_pos_plot.XData = app.Node_pos(:,1);
                app.node_pos_plot.YData = app.Node_pos(:,2);
                %app.node_pos_plot.ZData = app.Node_pos(:,q);
                app.node_pos_plot.CData = zeros(NumNodes,3);
                
                for n=1:NumNodes
                    app.node_text(n) = text(app.UIAxes,app.Node_pos(n,1),app.Node_pos(n,2)+2,int2str(n),...
                        'Color','blue','FontSize',12,'tag',sprintf('cell%i',n),...
                        'ButtonDownFcn',@app.event_handle);
                end
                app.node_connections = cell2mat(app.raw_nodes(2:end,3));
            else
                error('The node position is empty.');
                return;
            end
            if ~isempty(app.Probe_pos)
                app.probe_pos_plot.XData = app.Probe_pos(:,1);
                app.probe_pos_plot.YData = app.Probe_pos(:,2);
                %app.probe_pos_plot.ZData = app.Probe_pos(:,q);
            else
                error('The Probe position is empty.');
                return;
            end
            % Draw the paths 
            app.path_plot = [];
            % IMPORTANT HERE: DETERMINE HOW TO MAKE THE PATHS 3D IF NEEDED AT ALL
            app.path_connections = [1:NumPaths.'; cell2mat(app.raw_paths(2:end,3)).'; cell2mat(app.raw_paths(2:end,4)).'].';
            
            for n=1:NumPaths
                node1 = find(app.node_connections == app.path_connections(n,2));
                node2 = find(app.node_connections == app.path_connections(n,3));                
                app.path_plot(n)=line([app.Node_pos(node1,1),app.Node_pos(node2,1)],...
                    [app.Node_pos(node1,2),app.Node_pos(node2,2)],'LineWidth',1.5,'Color',[0 0 0],...
                    'Parent',app.UIAxes,'tag',sprintf('path%i',n),'ButtonDownFcn',@app.event_handle);
            end
            % Create the legend
            legend([app.node_pos_plot,app.probe_pos_plot],{'Node','Probe'});
            I = imread('heartoutline.jpg'); 
            x = [20 180];
            y = [28 192];
            I = flipdim(I, 1);
            h= image(app.UIAxes,x,y,I,'ButtonDownFcn',@app.event_handle); 
            uistack(h,'bottom')

        end
        function reset(app)
            cla(app.UIAxes,'reset')
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            delete(findobj('tag','myOgTrace'))
            delete(findobj('tag','myAlteredTrace'))
            delete(findobj('tag','myAlteredRestCurve'))
            delete(findobj('tag','myOgRestCurve'))
            hold(app.UIAxes,'on')
            hold(app.UIAxes2,'on')
            hold(app.UIAxes3,'on')
            app.newnodebool = false;
            app.delnodebool = false;
            app.editnodebool = false;
            delete(app.tooltipObj)
            app.editnodeind = 0;
            app.delnodeind = 0;
            app.node1ind = 0;
            app.node2ind = 0;
            app.node1bool = false;
            app.node2bool = false;
            app.Node_pos = [];
            app.node_connections = [];
            app.Probe_pos = [];
            app.probe_connections = [];
            app.path_plot = [];
            app.path_connections = [];
            app.node_pos_plot = scatter(app.UIAxes,[],[],75,'filled',...
                'LineWidth',2,'Marker','o','CData',[0 0 0],'ButtonDownFcn',@app.event_handle);
            app.probe_pos_plot = scatter(app.UIAxes,[],[],'LineWidth',1.5,...
                'Marker','d','CData',[0 0 0],'ButtonDownFcn',@app.event_handle);
            app.Probe_pos =[];
            app.raw_nodes = [];
            app.raw_nodes_reserve = [];
            app.raw_paths = [];
            app.raw_paths_reserve = [];
            app.raw_probes = [];
            app.raw_probes_reserve = [];
            app.editpathind =0;
            app.delpathind = 0;
            app.delpathbool = false;
            app.editpathbool = false;
            app.firsttime_path = true;
            app.path_ind_visual = 0;
            app.node_text =[];
            app.ogtrace =[];
            app.newtrace =[];
            app.editprobebool = false;
            app.delprobebool = false;
            app.newprobebool = false;
            app.probe2bool = false;
            app.editprobeind = 0;
            app.delprobeind = 0;
            set(app.ProbeTypeEditField,'Value',app.ProbeTypeEditField.Placeholder);
            set(app.ProbeNumberEditField,'Value',app.ProbeNumberEditField.Placeholder);
            set(app.Node2jEditField,'Value',app.Node2jEditField.Placeholder);
            set(app.Node1iEditField,'Value',app.Node1iEditField.Placeholder);
            set(app.Node2jEditField_2,'Value',app.Node2jEditField_2.Placeholder);
            set(app.Node1iEditField_2,'Value',app.Node1iEditField_2.Placeholder);
            set(app.NodeTypeEditField,'Value',app.NodeTypeEditField.Placeholder);
            set(app.NodeNumberEditField,'Value',app.NodeNumberEditField.Placeholder);
            %set(app.AttributeValueEditField,'Value',app.AttributeValueEditField.Placeholder);
            %set(app.ParameterValueEditField,'Value',app.ParameterValueEditField.Placeholder);
            %set(app.ADelaymsEditField,'Value',app.ADelaymsEditField.Placeholder);
            %set(app.RDelaymsEditField,'Value',app.RDelaymsEditField.Placeholder);
            %set(app.AttributeValueEditField_2,'Value',app.AttributeValueEditField_2.Placeholder);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            
            disp('Startup initiated')
            hold(app.UIAxes,'on')
            hold(app.UIAxes2,'on')
            app.node_pos_plot = scatter(app.UIAxes,[],[],75,'filled',...
                'LineWidth',2,'Marker','o','CData',[0 0 0],'ButtonDownFcn',@app.event_handle);
            app.probe_pos_plot = scatter(app.UIAxes,[],[],'LineWidth',1.5,...
                'Marker','d','CData',[0 0 0],'ButtonDownFcn',@app.event_handle);
            

            xlsx_file = 'Heart_N3_second.xlsx';
            app.ms_or_not = false;
            %HERE: add in selecting possibility for ms or s at startup??
            if app.ms_or_not
                [~,~,app.raw_nodes]=xlsread(xlsx_file,'Node');
                [~,~,app.raw_paths]=xlsread(xlsx_file,'Path');
            else
                [~,~,app.raw_nodes]=xlsread(xlsx_file,'Node_second');
                [~,~,app.raw_paths]=xlsread(xlsx_file,'Path_second');
            end
            [~,~,app.raw_probes]=xlsread(xlsx_file,'Probe');
            app.raw_nodes(:,end-2) = [];
            app.raw_nodes(end,:) = [];
            app.raw_nodes_reserve = app.raw_nodes;
            app.raw_paths_reserve = app.raw_paths;
            app.raw_probes_reserve = app.raw_probes;
            app.nodeTypesAttr = dictionary(["N", "M", "NM"],{app.raw_nodes(1,4:24),app.raw_nodes(1,25:47),app.raw_nodes(1,4:47)});
            app.AttributeDropDown_2.Items = app.raw_paths(1,5:15);
            app.PathAttributeDropDown.Items = app.raw_paths(1,[5:15,21:22]); 
            if app.ms_or_not
                assignin('base','stepsize',1)
                assignin('base','solvertime',2000)
                assignin('base','buffer',599);
                assignin('base','unit_conversion',2);
                assignin('base','timescale','ms');
                assignin('base','restEnd',30000);
                assignin('base','trim',10);
                assignin('base','duration',3);
                assignin('base','initial',700);
            else
                assignin('base','solvertime',2.0);
                assignin('base','stepsize',0.0005);
                assignin('base','buffer',0.599);
                assignin('base','unit_conversion',1);
                assignin('base','timescale','s');
                assignin('base','restEnd',30);
                assignin('base','trim',0.01);
                assignin('base','duration',0.003);
                assignin('base','initial',0.7);
            end

            node_temp = cell2mat(app.raw_nodes(2:end,4:end));         
            node_temp(:,45:46)=40000;
            node_temp(isnan(node_temp))=40000;
            [app.node_presets,app.unique_node_idx] = unique(node_temp,'rows');
            app.node_presets = changem(app.node_presets,NaN,40000);
            app.unique_node_idx = app.unique_node_idx +1;
            % Dictionary for the drop down menu
            app.unique_node_presets = struct('N', [], 'M', [], 'NM', []);
            % Back end dictionary for saving the correct preset
            app.node_preset_dict = dictionary;
            for idx = 1:length(app.unique_node_idx)
                curr =app.raw_nodes_reserve{app.unique_node_idx(idx),1};
                if strcmp(app.raw_nodes_reserve{app.unique_node_idx(idx),2},"N")
                    app.unique_node_presets.N{end+1} = curr;
                elseif strcmp(app.raw_nodes_reserve{app.unique_node_idx(idx),2},"M")
                    app.unique_node_presets.M{end+1} = curr;
                elseif strcmp(app.raw_nodes_reserve{app.unique_node_idx(idx),2},"NM")              
                    app.unique_node_presets.NM{end+1} = curr;
                end
                app.node_preset_dict(curr) = {app.node_presets(idx,:)};
            end

            app.NodePresetsDropDown.Items = app.unique_node_presets.N;
            app.NodeAttributeDropDown.Items = app.nodeTypesAttr{"NM"};            


            path_temp = cell2mat(app.raw_paths_reserve(2:end,5:15));
            [app.path_presets,app.unique_path_idx] = unique(path_temp,'rows');
            app.unique_path_idx = app.unique_path_idx +1;
            app.path_preset_dict = dictionary;

            strings = cell(length(app.unique_path_idx),1);
            for idx = 1:length(app.unique_path_idx)
                strings{idx} = append(app.raw_paths_reserve{app.unique_path_idx(idx),1},' ','-',' ',...
                    app.raw_paths_reserve{app.unique_path_idx(idx),2});
                app.path_preset_dict(strings{idx}) = {app.path_presets(idx,:)};
            end
            app.PathPresetDropDown.Items = strings;

            NumPaths = size(cell2mat(app.raw_paths(2:end,4)),1);
            NumNodes = size(cell2mat(app.raw_nodes(2:end,4)),1);
            NumProbes = size(cell2mat(app.raw_probes(2:end,2)),1);
            app.Node_pos= cell2mat(app.raw_nodes(2:end,end-3:end-2));
            app.Probe_pos= cell2mat(app.raw_probes(2:end,3:4));
            app.UIAxes.XLim = [round(min(app.Node_pos(:,1)),-1)-10 round(max(app.Node_pos(:,1)),-1)+10];
            app.UIAxes.YLim = [round(min(app.Node_pos(:,2)),-1)-10 round(max(app.Node_pos(:,2)),-1)+10];
            app.node_text = [];
            % Display the nodes and probes; Store the node and probe positions
            if ~isempty(app.Node_pos)
                app.node_pos_plot.XData = app.Node_pos(:,1);
                app.node_pos_plot.YData = app.Node_pos(:,2);
                %app.node_pos_plot.ZData = app.Node_pos(:,q);
                app.node_pos_plot.CData = zeros(NumNodes,3);
                
                for n=1:NumNodes
                    app.node_text(n) = text(app.UIAxes,app.Node_pos(n,1),app.Node_pos(n,2)+2,int2str(n),...
                        'Color','blue','FontSize',12,'tag',sprintf('cell%i',n),...
                        'ButtonDownFcn',@app.event_handle);
                end
                app.node_connections = cell2mat(app.raw_nodes(2:end,3));
            else
                error('The node position is empty.');
                return;
            end
            if ~isempty(app.Probe_pos)
                app.probe_pos_plot.XData = app.Probe_pos(:,1);
                app.probe_pos_plot.YData = app.Probe_pos(:,2);
                %app.probe_pos_plot.ZData = app.Probe_pos(:,q);
                app.probe_pos_plot.CData = zeros(NumProbes,3);
                app.probe_connections = cell2mat(app.raw_probes(2:end,2));
            else
                error('The Probe position is empty.');
                return;
            end
            % Draw the paths 
            app.path_plot = [];
            app.path_connections = [1:NumPaths.'; cell2mat(app.raw_paths(2:end,3)).'; cell2mat(app.raw_paths(2:end,4)).'].';
            
            for n=1:NumPaths
                node1 = find(app.node_connections == app.path_connections(n,2));
                node2 = find(app.node_connections == app.path_connections(n,3));

                app.path_plot(n)=line([app.Node_pos(node1,1),app.Node_pos(node2,1)],...
                    [app.Node_pos(node1,2),app.Node_pos(node2,2)],'LineWidth',1.5,'Color',[0 0 0],...
                    'Parent',app.UIAxes,'tag',sprintf('path%i',n),'ButtonDownFcn',@app.event_handle);
            end
            % Create the legend
            legend([app.node_pos_plot,app.probe_pos_plot],{'Node','Probe'});
            I = imread('heartoutline.jpg'); 
            x = [20 180];
            y = [28 192];
            I = flipdim(I, 1);
            h = image(app.UIAxes,x,y,I,'ButtonDownFcn',@app.event_handle); 
            uistack(h,'bottom')
            app.firsttime_path = true;
            calling = evalin("base","solvertime");
            app.PulsePeriodEditField.Value = 0.5*calling;
        end

        % Button pushed function: SelectLocationButton
        function selectLocation(app, event)
            app.newnodebool = true;
            app.SelectLocationButton.Enable = "off";
            app.SelectNodeButton.Enable = "off";
            app.SelectNodeButton_2.Enable = "off";
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            cursor = app.UIAxes.CurrentPoint;
            x = cursor(1,1);
            y = cursor(1,2);
            xLimits = app.UIAxes.XLim;
            yLimits = app.UIAxes.YLim;
            delete(app.tooltipObj)
            % Check that the mouse click is within the Axis
            if (x > min(xLimits) && x < max(xLimits) && ...
                y > min(yLimits) && y < max(yLimits))
                if ~isempty(app.Node_pos)
                    [~,dist1] = dsearchn([x,y],app.Node_pos);
                    [~,ind1]=min(dist1);
                    str = append('Node: ',int2str(app.raw_nodes{ind1+1,3}),' = ', app.raw_nodes(ind1+1,1));
                    if strcmp('N',app.raw_nodes(ind1+1,2))
                        str = [str,'Cell Type: N'];
                        back_color = [1 1 .3];
                    elseif strcmp('NM',app.raw_nodes(ind1+1,2))
                        str = [str,'Cell Type: NM'];
                        back_color = [1 .8 .4];
                    elseif strcmp('M',app.raw_nodes(ind1+1,2))
                        str = [str,'Cell Type: M'];
                        back_color = [1 .8 .8];
                    end
                    app.tooltipObj = text(app.UIAxes,x+5,y+5,str,...
                        'backgroundcolor',back_color,'tag','mytooltip','edgecolor',[0 0 0],...
                        'hittest','off');
                else 
                    ind1 = 0;
                    app.newnodebool = false;
                    app.delnodebool = false;
                    app.editnodebool = false;
                    app.node1bool = false;
                    app.node2bool = false;
                end
                if ~isempty(app.Probe_pos) && any([app.delprobebool,...
                        app.editprobebool,app.newprobebool,...
                        app.probe2bool])
                    [~,dist1] = dsearchn([x,y],app.Probe_pos);
                    [~,ind2]=min(dist1);
                else
                    ind2 = 0;                    
                    app.delprobebool = false;
                    app.editprobebool = false;
                    app.newprobebool = false;
                    app.probe2bool = false;
                end

                if app.editprobebool
                    app.editprobeind = ind2;
                    c = get(app.probe_pos_plot,'CData');
                    color = [0 0 1];        
                    [q, idx] = ismember(color,c,'rows');
                    if q %app.editprobeind previous
                        if idx == app.delprobeind
                            c(idx,:) = [1 0 0];
                        else
                            c(idx,:) = [0 0 0];
                        end
                    end
                    c(ind2,:) = color;
                    set(app.probe_pos_plot,'XData',app.Probe_pos(:,1),'YData',app.Probe_pos(:,2),'CData',c);
                    exact_match = strcmp(app.raw_probes(1,:), app.ParameterDropDown.Value);
                    idx = find(exact_match);
                    app.ParameterValueEditField.Value = app.raw_probes{app.editprobeind+1,idx};
                    set(app.ProbeTypeEditField,'Value',app.raw_probes{app.editprobeind+1,1});                    
                    set(app.ProbeNumberEditField,'Value',sprintf('%s (%i)',app.raw_probes{app.editprobeind+1,1}, ind2));


                    app.SelectEndLocationButton.Enable = "on";
                    app.SelectStartLocationButton.Enable = "on";
                    app.SelectProbeButton.Enable = "on";
                    app.SelectProbeButton_2.Enable = "on";
                    app.UpdateButton_3.Enable = "on";
                    if app.raw_probes{app.editprobeind+1,idx} ~= app.raw_probes_reserve{app.editprobeind+1,idx}
                        app.ResetButton_3.Enable = "on";
                    end
                    delete(app.tooltipObj)
                    app.editprobebool = false;
                elseif app.delprobebool
                    app.delprobeind = ind2;
                    c = get(app.probe_pos_plot,'CData');
                    color = [1 0 0];        
                    [q, idx] = ismember(color,c,'rows');
                    if q %app.delprobeind previous
                        if idx == app.editprobeind
                            c(idx,:) = [0 0 1];
                        else
                            c(idx,:) = [0 0 0];
                        end
                    end
                    c(ind2,:) = color;
                    set(app.probe_pos_plot,'XData',app.Probe_pos(:,1),'YData',app.Probe_pos(:,2),'CData',c);

                    app.SelectEndLocationButton.Enable = "on";
                    app.SelectStartLocationButton.Enable = "on";
                    app.SelectProbeButton.Enable = "on";
                    app.SelectProbeButton_2.Enable = "on";
                    app.CancelButton_4.Enable = "on";
                    app.DeleteProbeButton.Enable = "on";
                    delete(app.tooltipObj)
                    app.delprobebool = false;
                elseif app.newprobebool
                    app.SelectStartLocationButton.Enable = "on";
                    app.SelectEndLocationButton.Enable = "on";
                    app.SelectProbeButton.Enable = "on";
                    app.SelectProbeButton_2.Enable = "on";
                    app.CancelButton_3.Enable = "on";
                    delete(app.tooltipObj)

                    c = get(app.probe_pos_plot,'CData');
                    x_temp = get(app.probe_pos_plot,'XData');
                    y_temp = get(app.probe_pos_plot,'YData');
                    x_temp(end+1) = x;
                    y_temp(end+1) = y;
                    c(end+1,:) = [0 1 0];
                    set(app.probe_pos_plot,'XData',x_temp,'YData',y_temp,'CData',c);
                    app.newprobebool = false;
                    app.SaveProbeButton.Enable = "on";
                elseif app.probe2bool
                    app.SelectStartLocationButton.Enable = "on";
                    app.SelectEndLocationButton.Enable = "on";
                    app.SelectProbeButton.Enable = "on";
                    app.SelectProbeButton_2.Enable = "on";
                    app.CancelButton_3.Enable = "on";
                    delete(app.tooltipObj)

                    c = get(app.probe_pos_plot,'CData');
                    x_temp = get(app.probe_pos_plot,'XData');
                    y_temp = get(app.probe_pos_plot,'YData');
                    x_temp(end+1) = x;
                    y_temp(end+1) = y;
                    c(end+1,:) = [0 1 1];
                    set(app.probe_pos_plot,'XData',x_temp,'YData',y_temp,'CData',c);
                    app.probe2bool = false;
                    app.SaveProbeButton.Enable = "on";
                end


                if app.newnodebool
                    app.newnodebool = false;
                    app.SelectNodeButton.Enable = "on";
                    app.SelectNodeButton_2.Enable = "on";
                    app.SaveNodeButton.Enable = "on";
                    app.CancelButton_2.Enable = "on";
                    delete(app.tooltipObj)

                    c = get(app.node_pos_plot,'CData');
                    x_temp = get(app.node_pos_plot,'XData');
                    y_temp = get(app.node_pos_plot,'YData');
                    x_temp(end+1) = x;
                    y_temp(end+1) = y;
                    c(end+1,:) = [0 1 0];
                    set(app.node_pos_plot,'XData',x_temp,'YData',y_temp,'CData',c);
                elseif app.delnodebool
                    app.delnodebool = false;
                    app.delnodeind = ind1;
                    app.SelectLocationButton.Enable = "on";
                    app.SelectNodeButton.Enable = "on";
                    app.SelectNodeButton_2.Enable = "on";
                    app.DeleteNodeButton.Enable = "on";
                    app.CancelButton.Enable = "on";
                    %set the node information based on nearest node
                    c = get(app.node_pos_plot,'CData');
                    color = [1 0 0];        
                    [q, idx] = ismember(color,c,'rows');
                    if q %app.delnodeind previous
                        if idx == app.editnodeind
                            c(idx,:) = [0 0 1];
                        elseif idx == app.node1ind
                            c(idx,:) = [1 1 0];
                        elseif idx == app.node2ind
                            c(idx,:) = [1 0 1];
                        else
                            c(idx,:) = [0 0 0];
                        end
                    end
                    c(ind1,:) = color;
                    set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
                elseif app.editnodebool
                    app.editnodebool = false;
                    app.editnodeind = ind1;
                    app.AttributeDropDown.Items = app.nodeTypesAttr{app.raw_nodes(ind1+1,2)};
                    app.SelectLocationButton.Enable = "on";
                    app.SelectNodeButton.Enable = "on";
                    app.SelectNodeButton_2.Enable = "on";
                    %set the node information based on nearest node
                    c = get(app.node_pos_plot,'CData');
                    color = [0 0 1];        
                    [q, idx] = ismember(color,c,'rows');
                    % Changing the previously selected node if it exists
                    if q 
                        if idx == app.delnodeind
                            c(idx,:) = [1 0 0];
                        elseif idx == app.node1ind
                            c(idx,:) = [1 1 0];
                        elseif idx == app.node2ind
                            c(idx,:) = [1 0 1];
                        else
                            c(idx,:) = [0 0 0];
                        end
                    end
                    c(ind1,:) = color; 
                    set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
                    set(app.NodeNumberEditField,'Value',sprintf('%s (%i)',app.raw_nodes{app.editnodeind+1,1}, ind1));
                    set(app.NodeTypeEditField,'Value',app.raw_nodes{app.editnodeind+1,2});
                    exact_match = strcmp(app.raw_nodes(1,:), app.AttributeDropDown.Value);
                    idx = find(exact_match);
        
                    if length(idx) > 1
                        if strcmp(app.NodeTypeEditField.Value,"M")
                            idx = idx(2);
                        elseif strcmp(app.NodeTypeEditField.Value,"N")
                            idx = idx(1);
                        elseif strcmp(app.NodeTypeEditField.Value,"NM")
                            %HERE: HOW TO HAVE NM WORK??
                            idx = idx(1);
                        end
                    end
                    set(app.AttributeValueEditField,'Value',app.raw_nodes{app.editnodeind+1,idx});
                    app.UpdateButton.Enable = "on";
                    app.PlotAPButton.Enable = "on";
                    app.PlotRestitutionCurveButton.Enable = "on";
                    if strcmp(app.NodeTypeEditField.Value,'M')
                        app.ExternalPulseCheckBox.Value = 1;
                        app.ExternalPulseCheckBox.Enable = "off";
                        app.PulsePeriodEditField.Enable = "on";
                        app.VTidx = 41;
                    elseif strcmp(app.NodeTypeEditField.Value,'NM')
                        app.VTidx = 41;
                        app.ExternalPulseCheckBox.Enable = "on";
                        app.ExternalPulseCheckBox.Value = 0;
                    else
                        app.VTidx = 14;
                        app.ExternalPulseCheckBox.Enable = "on";
                        app.ExternalPulseCheckBox.Value = 0;
                    end
                    if app.raw_nodes{app.editnodeind+1,idx} ~= app.raw_nodes_reserve{app.editnodeind+1,idx}
                        app.ResetButton.Enable = "on";
                    else
                        app.ResetButton.Enable = "off";
                    end
                elseif app.node1bool
                    app.node1ind = ind1;
                    c = get(app.node_pos_plot,'CData');
                    color = [1 1 0];        
                    [q, idx] = ismember(color,c,'rows');
                    if q %app.node1ind previous
                        if idx == app.editnodeind
                            c(idx,:) = [0 0 1];
                        elseif idx == app.delnodeind
                            c(idx,:) = [1 0 0];
                        elseif idx == app.node2ind
                            c(idx,:) = [1 0 1];
                        else
                            c(idx,:) = [0 0 0];
                        end
                    end
                    c(ind1,:) = color;
                    set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
                    app.Node1iEditField_2.Value = sprintf('%s (%i)',app.raw_nodes{app.node1ind+1,1},ind1);        
                    app.SelectNode2Button.Enable = "on";
                    app.SelectNode1Button.Enable = "on";
                    app.CancelPathCreationButton.Enable = "on";
                    app.node1bool = false;

                elseif app.node2bool
                    app.node2ind = ind1;
                    c = get(app.node_pos_plot,'CData');
                    color = [1 0 1];        
                    [q, idx] = ismember(color,c,'rows');
                    if q %app.node2ind previous
                        if idx == app.editnodeind
                            c(idx,:) = [0 0 1];
                        elseif idx == app.delnodeind
                            c(idx,:) = [1 0 0];
                        elseif idx == app.node1ind
                            c(idx,:) = [1 1 0];
                        else
                            c(idx,:) = [0 0 0];
                        end
                    end
                    c(ind1,:) = color;
                    set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
                    app.Node2jEditField_2.Value = sprintf('%s (%i)',app.raw_nodes{app.node2ind+1,1},ind1);                    
                    app.SelectNode2Button.Enable = "on";
                    app.SelectNode1Button.Enable = "on";
                    app.CancelPathCreationButton.Enable = "on";
                    app.node2bool = false;

                    if ismember(app.raw_nodes{app.node2ind+1,1},app.raw_nodes(:,1)) && ...
                        ismember(app.raw_nodes{app.node1ind+1,1},app.raw_nodes(:,1))
                        app.SavePathButton.Enable = "on";
                    end

                end

                if app.editpathbool
                    %set the path information based on nearest two nodes
                    if ~app.firsttime_path
                        node1idx = find(app.node_connections == app.raw_paths{app.editpathind+1,3});
                        node2idx = find(app.node_connections == app.raw_paths{app.editpathind+1,4});
                        delete(app.path_plot(app.editpathind))
                        delete(findobj('tag',sprintf('path%i',app.editpathind)))
                        if app.editpathind == app.path_ind_visual
                            colour = [1 0 0];
                        else
                            colour = [0 0 0];
                        end
                        app.path_plot(app.editpathind)=line([app.Node_pos(node1idx,1),...
                            app.Node_pos(node2idx,1)],...
                            [app.Node_pos(node1idx,2),app.Node_pos(node2idx,2)],'LineWidth',1.5,'Color',colour,...
                            'Parent',app.UIAxes,'tag',sprintf('path%i',app.editpathind));
                        
                    else
                        app.firsttime_path = false;
                    end
                    app.editpathind = app.nearest_line([x,y]);
                    if app.editpathind == 0
                        app.editpathbool = false;
                        return
                    end
                    node1idx = find(app.node_connections == app.raw_paths{app.editpathind+1,3});
                    node2idx = find(app.node_connections == app.raw_paths{app.editpathind+1,4});
                    delete(app.path_plot(app.editpathind))
                    delete(findobj('tag',sprintf('path%i',app.editpathind)))
                    app.path_plot(app.editpathind)=line([app.Node_pos(node1idx,1),...
                        app.Node_pos(node2idx,1)],...
                        [app.Node_pos(node1idx,2),app.Node_pos(node2idx,2)],'LineWidth',1.5,'Color',[0 0 1],...
                        'Parent',app.UIAxes,'tag',sprintf('path%i',app.editpathind),'ButtonDownFcn',@app.event_handle);
                    legend([app.node_pos_plot,app.probe_pos_plot],{'Node','Probe'});
                    app.Node2jEditField.Value = sprintf('%s (%i)',app.raw_paths{app.editpathind+1,2},app.raw_paths{app.editpathind+1,4});
                    app.Node1iEditField.Value = sprintf('%s (%i)',app.raw_paths{app.editpathind+1,1},app.raw_paths{app.editpathind+1,3});
                    app.RDelaymsEditField.Value = app.raw_paths{app.editpathind+1,22};
                    app.ADelaymsEditField.Value = app.raw_paths{app.editpathind+1,21};
                    exact_match = strcmp(app.raw_paths(1,:), app.AttributeDropDown_2.Value);
                    idx = find(exact_match);
                    app.AttributeValueEditField_2.Value = app.raw_paths{app.editpathind+1,idx};
                    app.editpathbool = false;
                    delete(app.tooltipObj)
                    app.SelectPathButton.Enable = "on";
                    app.SelectPathButton_2.Enable = "on";
                    app.UpdateButton_2.Enable = "on";
                    if app.raw_paths{app.editpathind+1,idx} ~= app.raw_paths_reserve{app.editpathind+1,idx}
                        app.ResetButton_2.Enable = "on";       
                    else
                        app.ResetButton_2.Enable = "off";             
                    end
                elseif app.delpathbool % for deleting the path
                    app.delpathind = app.nearest_line([x,y]);
                    if app.delpathind == 0
                        app.delpathbool = false;
                        return
                    end
                    pathvis = find(ismember(app.path_connections(:,2:end), ...
                        [app.raw_paths{app.delpathind+1,3},app.raw_paths{app.delpathind+1,4}],'row'));
                    app.path_ind_visual = app.path_connections(pathvis,1);
                    node1idx = find(app.node_connections == app.raw_paths{app.delpathind+1,3});
                    node2idx = find(app.node_connections == app.raw_paths{app.delpathind+1,4});
                    delete(app.path_plot(app.path_ind_visual))
                    delete(findobj('tag',sprintf('path%i',app.path_ind_visual)))
                    app.path_plot(app.path_ind_visual)=line([app.Node_pos(node1idx,1),...
                        app.Node_pos(node2idx,1)],...
                        [app.Node_pos(node1idx,2),app.Node_pos(node2idx,2)],'LineWidth',1.5,'Color',[1 0 0],...
                        'Parent',app.UIAxes,'tag',sprintf('path%i',app.path_ind_visual),'ButtonDownFcn',@app.event_handle);
                    legend([app.node_pos_plot,app.probe_pos_plot],{'Node','Probe'});
                    app.delpathbool = false;
                    app.SelectPathButton.Enable = "off";
                    app.SelectPathButton_2.Enable = "on";
                    app.CancelPathDeletionButton.Enable = "on";
                    app.DeletePathButton.Enable = "on";
                    delete(app.tooltipObj)                    
                end
                drawnow update;
            end
            
        end

        % Button pushed function: SelectNodeButton
        function selectNodeDel(app, event)
            app.delnodebool = true;
            app.SelectLocationButton.Enable = "off";
            app.SelectNodeButton.Enable = "off";
            app.SelectNodeButton_2.Enable = "off";
        end

        % Button pushed function: SelectNodeButton_2
        function selectNodeEdit(app, event)
            app.editnodebool = true;
            app.SelectLocationButton.Enable = "off";
            app.SelectNodeButton.Enable = "off";
            app.SelectNodeButton_2.Enable = "off";
        end

        % Callback function: NodeTypeDropDown, NodeTypeDropDown, 
        % ...and 1 other component
        function presetChange(app, event)
            value = app.NodeTypeDropDown.Value;
            if strcmp(value,"N")
                app.NodePresetsDropDown.Items = app.unique_node_presets.N;            
            elseif strcmp(value,"M")
                app.NodePresetsDropDown.Items = app.unique_node_presets.M;            
            elseif strcmp(value,"NM")
                app.NodePresetsDropDown.Items = app.unique_node_presets.NM;            
            end
            
        end

        % Button pushed function: SaveNodeButton
        function saveNode(app, event)
            
            % Check that the node name is unique           
            if ismember(app.NodeNameEditField.Value,app.raw_nodes(:,1))
                f =msgbox("Cell Name already exists","Error","error");
                waitfor(f);
                return
            elseif strcmp(app.NodeNameEditField.Value,app.NodeNameEditField.Placeholder)
                f =msgbox("Enter a Cell Name","Error","error");
                waitfor(f);
                return
            end
            app.SelectLocationButton.Enable = "on";
            app.SaveNodeButton.Enable = "off";
            app.CancelButton_2.Enable = "off";
            c = get(app.node_pos_plot,'CData');
            x_temp = get(app.node_pos_plot,'XData');
            y_temp = get(app.node_pos_plot,'YData');
            x = x_temp(end);
            y = y_temp(end);
            app.Node_pos(end+1,:) = [x y];
            c(end,:) = [0 0 0];
            set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
            s = app.NodeNameEditField.Value;
            n = app.unique_num(app.node_connections);
            app.node_text(end+1) = text(app.UIAxes,app.Node_pos(end,1),app.Node_pos(end,2)+2,int2str(n),'Color','blue','FontSize',12,'tag',sprintf('cell%i',n));
            % Get the attributes for the new node
            type_node = app.NodeTypeDropDown.Value;
            atts = app.node_preset_dict(app.NodePresetsDropDown.Value);
            atts = atts{1};
            atts(45:46)= [x y];            
            % Save the new nodes attributes in the correct arrays 
            app.raw_nodes(end+1,:) = [s type_node n num2cell(atts)];
            app.raw_nodes_reserve(end+1,:) = [s type_node n num2cell(atts)];
            app.node_connections(end+1)=n;
        end

        % Button pushed function: CancelButton_2
        function cancelNodeSave(app, event)
            
            % Removing the node from the graph
            c = get(app.node_pos_plot,'CData');
            c(end,:) = [];
            
            set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
            app.SelectLocationButton.Enable = "on";
            app.SaveNodeButton.Enable = "off";
            app.CancelButton_2.Enable = "off";
        end

        % Button pushed function: DeleteNodeButton
        function deleteNode(app, event)
            app.DeleteNodeButton.Enable = "off";
            app.CancelButton.Enable = "off";

            % 1. Check for overlapping indices
            if app.editnodeind == app.delnodeind
                set(app.NodeNumberEditField,'Value',app.NodeNumberEditField.Placeholder);
                set(app.NodeTypeEditField,'Value',app.NodeTypeEditField.Placeholder)
                set(app.AttributeValueEditField,'Value',0.0);%app.AttributeValueEditField.Placeholder);
                app.SelectNodeButton_2.Enable = "on";
                app.ResetButton.Enable = "off";
                app.UpdateButton.Enable = "off";
                app.PlotAPButton.Enable = "off";
                app.editnodeind = 0;
            end
            if app.delnodeind == app.node1ind
                set(app.node1path, 'String', 'N/A');      
                b = findobj('tag','savepath');
                b.Enable = 'off';
                app.node1ind = 0;
            end
            if app.delnodeind == app.node2ind
                set(app.node2path, 'String', 'N/A');       
                b = findobj('tag','savepath');
                b.Enable = 'off';
                app.node2ind = 0;
            end
            % 2. Delete the node physically from the plot
            app.Node_pos(app.delnodeind,:) = [];
            app.node_connections(app.delnodeind) = [];
            c = get(app.node_pos_plot,'CData');
            c(app.delnodeind,:) = [];
            set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
            % Delete the node from the arrays - requires finding the exact
            % node index as per the raw array
            true_index = app.raw_nodes{app.delnodeind+1,3};
            partial1 =[app.raw_nodes{2:end,3}].';
            [index1,~] = find(partial1 == true_index);
            delete(app.node_text(index1))
            index1 = index1+1;            
            app.raw_nodes(index1,:) = [];
            app.raw_nodes_reserve(index1,:) = [];

            % 3. Delete the paths connected to the node being deleted
            partial1 =[app.raw_paths{2:end,3}; app.raw_paths{2:end,4}].';
            [index1,~] = find(partial1 == true_index);
            index_visual=index1;
            % Selecting multiple paths to delete from a row
            delete(app.path_plot([app.path_connections([index_visual])]))
            % alternatively this could also be an issue, try contains(array,pattern) instead
            if ismember(app.path_ind_visual,app.path_connections([index_visual],1))
                app.SelectPathButton.Enable = "on";
                app.CancelPathDeletionButton.Enable = "off";
                app.DeletePathButton.Enable = "off";
                app.path_ind_visual = 0;
                app.delpathind = 0;
            end
            if ismember(app.editpathind,app.path_connections([index_visual],1))
                app.firsttime_path = true;
                app.Node2jEditField.Value = "None";
                app.Node1iEditField.Value = "None";
                app.AttributeValueEditField_2.Value = 0;
                app.ADelaymsEditField.Value = 0;
                app.RDelaymsEditField.Value = 0;
                app.UpdateButton_2.Enable = "off";
                app.ResetButton_2.Enable = "off";
                app.editpathind = 0;
            end
            app.path_connections([index_visual],:) = [];
            index1 = index1+1;
            app.raw_paths(index1,:) = [];
            app.raw_paths_reserve(index1,:) = [];
            app.delnodeind = 0;
            delete(app.tooltipObj)
            drawnow update;
        end

        % Button pushed function: CancelButton
        function cancelNodeDel(app, event)
            app.DeleteNodeButton.Enable = "off";
            app.CancelButton.Enable = "off";
            app.SelectNodeButton.Enable = "on";

            c = get(app.node_pos_plot,'CData');
            color = [1 0 0];        
            [q, idx] = ismember(color,c,'rows');
            if q
                if(idx == app.editnodeind)
                    c(idx,:) = [0 0 1];
                elseif (idx == app.node1ind)
                    c(idx,:) = [1 1 0];
                elseif(idx == app.node2ind)
                    c(idx,:) = [1 0 1];
                else
                    c(idx,:) = [0 0 0];
                end
            end
            set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
            app.delnodeind = 0;

        end

        % Button pushed function: SelectNetworkConfigFileButton
        function selectConfigFile(app, event)
            
            % UI window allowing the operator to browse for and select a data file
            while (1)
                [fileName,filePath] = uigetfile({'*.mat';'*.xlsx'},'Load Heart Configuration File');
                if isequal(fileName,0)
                    empty_answer = questdlg('Do you really want to cancel?', ...
                        'Cancel File Load','Yes','No','Yes');
                    switch empty_answer
                        case 'Yes'
                            return
                        case 'No'
                    end
                else
                    break
                end
            end
            app.load_cfgfile(filePath,fileName)
        end

        % Button pushed function: SaveNetworkandQuitButton
        function saveNetwork(app, event)
            
            prompt = {'Output file choice:'};
            definput = {'Mat-file', 'XLSX-file'};
            [answer, tf] = listdlg('PromptString',prompt,'SelectionMode','single','ListString',definput);
            if strcmp(answer(1),'Mat-file')
                model.node_atts=app.raw_nodes;
                model.nodes_name=app.raw_nodes(:,1:2);
                model.path_atts=app.raw_paths;
                model.probes=app.raw_probes;
                PreBuild_unified(model)
            else
                [filename, location] = uiputfile({'*.xlsx'});                
                writecell(app.raw_nodes,fullfile(location,filename),'Sheet','Nodes')
                writecell(app.raw_paths,fullfile(location,filename),'Sheet','Paths','WriteMode','append')
                writecell(app.raw_probes,fullfile(location,filename),'Sheet','Probes','WriteMode','append')
            end
            delete(app);
        end

        % Value changed function: AttributeDropDown
        function editAttrChange(app, event)
            exact_match = strcmp(app.raw_nodes(1,:), app.AttributeDropDown.Value);
            idx = find(exact_match);
            if length(idx) > 1
                if strcmp(app.NodeTypeEditField.Value,"M")
                    idx = idx(2);
                elseif strcmp(app.NodeTypeEditField.Value,"N")
                    idx = idx(1);
                elseif strcmp(app.NodeTypeEditField.Value,"NM")
                    %HERE: HOW TO HAVE NM WORK??
                    idx = idx(1);
                end
            end
            value = app.NodeTypeEditField.Value;
            % HERE: need to accomodate the two different VT/VR for NM nodes
            if ~app.editnodeind
                app.AttributeValueEditField.Value = 0;
            else                
                app.AttributeValueEditField.Value = app.raw_nodes{app.editnodeind+1,idx};
                if app.raw_nodes{app.editnodeind+1,idx} ~= app.raw_nodes_reserve{app.editnodeind+1,idx} 
                    app.ResetButton.Enable = "on";
                else
                    app.ResetButton.Enable = "off";
                end
            end
            drawnow update;
        end

        % Button pushed function: PlotAPButton
        function plotAPtrace(app, event)
            
            if strcmp(app.NodeTypeEditField.Value,'M')
                assignin("base","pulse_amp",app.raw_nodes_reserve{app.editnodeind+1,app.VTidx}+1)
            else
                assignin("base","pulse_amp",0)
            end
            cla(app.UIAxes2);

            if app.ImportCellMLDataCheckBox.Value == 1
                time = app.cellML_time;
                trace = app.cellML_voltage;
                assignin('base','phase_delay',app.cellML_delay)
                assignin('base','stepsize',time(2)-time(1))
                assignin('base','solvertime',time(end))
                if time(2) < 1 % s
                    assignin('base','buffer',0.599); % for Libs_unified/Path_V3/Path Buffer_i and Buffer_j
                    assignin('base','unit_conversion',1); % used with the N node ms/s setting in RR
                else
                    assignin('base','buffer',599);
                    assignin('base','unit_conversion',1000);
                end   
                displayName = 'CellML';
            else
                assignin('base','phase_delay',0)
                disp('Plotting old trace')
                calling = evalin("base","solvertime");
                if app.ms_or_not
                    assignin("base","pulse_rate",round(0.5*calling,-2))
                else
                    assignin("base","pulse_rate",round(0.5*calling))
                end
                % plot the current nodes model trace
                if strcmp('N',app.NodeTypeEditField.Value)
                    assignin('base','params',[cell2mat(cat(2,app.raw_nodes_reserve(app.editnodeind+1,4:24),...
                        app.raw_nodes_reserve(app.editnodeind+1,end-3:end-2)))]);
                    test = load_system("N_v6_GUI.slx");
                elseif strcmp('M',app.NodeTypeEditField.Value)
                    assignin('base','params',[cell2mat(app.raw_nodes_reserve(app.editnodeind+1,25:end-2))]);
                    test = load_system("M_v4_GUI.slx");
                elseif strcmp('NM',app.NodeTypeEditField.Value)
                    assignin('base','params',[cell2mat(app.raw_nodes_reserve(app.editnodeind+1,4:end-2)) 1 1]);
                    test = load_system("NM_v4_GUI.slx");
                end
                qv = sim(test);
                time = qv.APvoltage.time;
                trace = qv.APvoltage.data;
                close_system(test)
                displayName='Normal Parameters';
            end
            line(app.UIAxes2,time,trace,'color',[0 0 0],'LineWidth',2,'tag','myOgTrace','DisplayName',displayName)
            calling = evalin("base","solvertime");
            stepsize = evalin("base","stepsize");
            if app.ExternalPulseCheckBox.Value == 1
                assignin("base","pulse_amp",app.raw_nodes{app.editnodeind+1,app.VTidx}+1)
                % Pulse width assurance its a multiple of the step size
                PW = 0.01*(app.PulsePeriodEditField.Value);
                y = mod(PW,stepsize);
                if y > 0
                    round_value = 100*stepsize*floor(PW/stepsize);
                else
                    round_value = app.PulsePeriodEditField.Value;
                end
                assignin("base","pulse_rate",round_value)
                app.PulsePeriodEditField.Value = round_value;
            else
                assignin("base","pulse_amp",0)             
                if app.ms_or_not
                    assignin("base","pulse_rate",round(0.5*calling,-2))
                else
                    assignin("base","pulse_rate",round(0.5*calling))
                end
            end
            
            disp('Plotting new trace')
            % plot the current nodes model trace
            if strcmp('N',app.NodeTypeEditField.Value)
                assignin('base','params',[cell2mat(cat(2,app.raw_nodes(app.editnodeind+1,4:24),...
                    app.raw_nodes(app.editnodeind+1,end-3:end-2)))]);
                test = load_system("N_v6_GUI.slx");
            elseif strcmp('M',app.NodeTypeEditField.Value)
                assignin('base','params',[cell2mat(app.raw_nodes(app.editnodeind+1,25:end-2))]);
                test = load_system("M_v4_GUI.slx");
            elseif strcmp('NM',app.NodeTypeEditField.Value)
                assignin('base','params',[cell2mat(app.raw_nodes(app.editnodeind+1,4:end-2)) 1 1]);
                test = load_system("NM_v4_GUI.slx");
            end
            qv = sim(test);
            time = qv.APvoltage.time;
            trace = qv.APvoltage.data;
            close_system(test)
            displayName='Updated Parameters';  
            line(app.UIAxes2,time,trace,'color',[0 1 0],'LineWidth',2,'tag','myAlteredTrace','DisplayName',displayName)
            legend(app.UIAxes2)
            drawnow update;

        end

        % Button pushed function: SelectNode1Button
        function selectPathNode1(app, event)
            app.node1bool = true;
            app.SelectNode1Button.Enable = "off";
            app.SelectNode2Button.Enable = "off";            
        end

        % Button pushed function: SelectNode2Button
        function selectPathNode2(app, event)
            app.node2bool = true;
            app.SelectNode1Button.Enable = "off";
            app.SelectNode2Button.Enable = "off";
        end

        % Button pushed function: SelectPathButton
        function selectPathDel(app, event)
            app.delpathbool = true;
            app.SelectPathButton.Enable = "off";
            app.SelectPathButton_2.Enable = "off";
        end

        % Button pushed function: DeletePathButton
        function deletePath(app, event)
            app.DeletePathButton.Enable = "off";
            app.CancelPathDeletionButton.Enable = "off";
            app.SelectPathButton.Enable = "on";
            if app.path_ind_visual == app.editpathind
                app.firsttime_path = true;
                app.Node1iEditField.Value = "None";
                app.Node2jEditField.Value = "None";
                app.RDelaymsEditField.Value = 0;
                app.ADelaymsEditField.Value = 0;
                app.AttributeValueEditField_2.Value = 0;
                app.editpathind = 0;
                app.SelectPathButton_2.Enable = "on";
                app.UpdateButton_2.Enable = "off";
                app.ResetButton_2.Enable = "off";
            end
            delete(app.path_plot(app.path_ind_visual))
            delete(findobj('tag',sprintf('path%i',app.path_ind_visual)));
            idx = find(ismember(app.path_connections(:,1),app.path_ind_visual,"rows"));
            app.path_connections(idx,:) = [];
            app.raw_paths(app.delpathind+1,:) = [];
            app.raw_paths_reserve(app.delpathind+1,:) = [];
            app.path_ind_visual = 0;
            app.delpathind = 0;
            drawnow update;

        end

        % Button pushed function: CancelPathDeletionButton
        function cancelPathDel(app, event)
            app.SelectPathButton.Enable = "on";
            app.CancelPathDeletionButton.Enable = "off";
            app.DeletePathButton.Enable = "off";
            [node1idx,~] = find(app.node_connections == app.raw_paths{app.delpathind+1,3});
            [node2idx,~] = find(app.node_connections == app.raw_paths{app.delpathind+1,4});
            delete(app.path_plot(app.path_ind_visual))
            delete(findobj('tag',sprintf('path%i',app.path_ind_visual)))
            if app.editpathind == app.path_ind_visual
                colour = [0 0 1];
            else
                colour = [0 0 0];
            end
            app.path_plot(app.path_ind_visual)=line([app.Node_pos(node1idx,1),...
                app.Node_pos(node2idx,1)],...
                [app.Node_pos(node1idx,2),app.Node_pos(node2idx,2)],...
                'LineWidth',1.5,'Color',colour,'Parent',app.UIAxes,'tag',...
                sprintf('path%i',app.path_ind_visual),'ButtonDownFcn',@app.event_handle);
            legend([app.node_pos_plot,app.probe_pos_plot],{'Node','Probe'});
            app.path_ind_visual = 0;           
            app.delpathind = 0;
        end

        % Button pushed function: CancelPathCreationButton
        function cancelPathSave(app, event)
            app.node1ind = 0;
            app.node2ind = 0;
            app.SavePathButton.Enable = "off";
            app.CancelPathCreationButton.Enable = "off";
            app.Node2jEditField_2.Value = "None";
            app.Node1iEditField_2.Value = "None";
            c = get(app.node_pos_plot,'CData');
            color = [1 1 0];        
            [q, idx] = ismember(color,c,'rows');
            if q
                if (idx == app.editpathind)
                    c(idx,:) = [0 0 1];
                elseif (idx == app.delpathind)
                    c(idx,:) = [1 0 0];
                else
                    c(idx,:) = [0 0 0];
                end
            end
            color = [1 0 1];     
            [q, idx] = ismember(color,c,'rows');
            if q
                if (idx == app.editpathind)
                    c(idx,:) = [0 0 1];
                elseif(idx == app.delpathind)
                    c(idx,:) = [1 0 0];
                else
                    c(idx,:) = [0 0 0];
                end
            end
            set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
            app.Node2jEditField_2.Value = "None";
            app.Node1iEditField_2.Value = "None";
        end

        % Button pushed function: SavePathButton
        function savePath(app, event)
            app.SavePathButton.Enable = "off";
            app.CancelPathCreationButton.Enable = "off";
            n = app.unique_num(app.path_connections(:,1));
            app.path_plot(end+1)=line([app.Node_pos(app.node1ind,1),app.Node_pos(app.node2ind,1)],...
             [app.Node_pos(app.node1ind,2),app.Node_pos(app.node2ind,2)],'LineWidth',1.5,'Color',[0 0 0],...
             'Parent',app.UIAxes,'tag',sprintf('path%i',n));
            app.path_connections(end+1,:) = [n, app.node1ind, app.node2ind];
            legend([app.node_pos_plot,app.probe_pos_plot],{'Node','Probe'});
            atts = app.path_preset_dict(app.PathPresetDropDown.Value);
            atts = atts{1};
            x1 = app.Node_pos(app.node1ind,1);
            x2 = app.Node_pos(app.node2ind,1);
            y1 = app.Node_pos(app.node1ind,2);
            y2 = app.Node_pos(app.node2ind,2);
            dist = sqrt((x1-x2)^2+(x1-x2)^2);
            adelay = dist/atts(1);
            rdelay = dist/atts(6);
            update=[app.raw_nodes(app.node1ind+1,1) app.raw_nodes(app.node2ind+1,1) ...
                app.node1ind app.node2ind...
                num2cell(atts) x1 y1 x2 y2 dist adelay rdelay NaN NaN NaN];
            
            app.raw_paths(end+1,:) = update;
            app.raw_paths_reserve(end+1,:) = update;
            app.Node1iEditField_2.Value = "None";
            app.Node2jEditField_2.Value = "None";
            app.node1ind = 0;
            app.node2ind = 0;
            c = get(app.node_pos_plot,'CData');
            color = [1 1 0];        
            [q, idx] = ismember(color,c,'rows');
            if q
                if (idx == app.editpathind)
                    c(idx,:) = [0 0 1];
                elseif (idx == app.delpathind)
                    c(idx,:) = [1 0 0];
                else
                    c(idx,:) = [0 0 0];
                end
            end
            color = [1 0 1];     
            [q, idx] = ismember(color,c,'rows');
            if q
                if (idx == app.editpathind)
                    c(idx,:) = [0 0 1];
                elseif (idx == app.delpathind)
                    c(idx,:) = [1 0 0];
                else
                    c(idx,:) = [0 0 0];
                end
            end
            set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);

        end

        % Button pushed function: SelectPathButton_2
        function selectPathEdit(app, event)
            app.editpathbool = true;
            app.SelectPathButton.Enable = "off";
            app.SelectPathButton_2.Enable = "off";
        end

        % Value changed function: AttributeDropDown_2
        function editPathChange(app, event)
            exact_match = strcmp(app.raw_paths(1,:), app.AttributeDropDown_2.Value);
            idx = find(exact_match);
            if ~app.editpathind
                app.AttributeValueEditField_2.Value = 0;
            else
                app.AttributeValueEditField_2.Value = app.raw_paths{app.editpathind+1,idx};
                if app.raw_paths{app.editpathind+1,idx} ~= app.raw_paths_reserve{app.editpathind+1,idx}
                    app.ResetButton_2.Enable = "on";       
                else
                    app.ResetButton_2.Enable = "off";             
                end 
            end
            drawnow update;
        end

        % Button pushed function: UpdateButton_2
        function updatePathAttr(app, event)
            exact_match = strcmp(app.raw_paths(1,:), app.AttributeDropDown_2.Value);
            idx = find(exact_match);            
            app.raw_paths{app.editpathind+1,idx} = app.AttributeValueEditField_2.Value;
            if idx == 5
                app.raw_paths{app.editpathind+1,21} = app.raw_paths{app.editpathind+1,20}/app.raw_paths{app.editpathind+1,5};
                app.ADelaymsEditField.Value = app.raw_paths{app.editpathind+1,21};
            elseif idx == 10
                app.raw_paths{app.editpathind+1,22} = app.raw_paths{app.editpathind+1,20}/app.raw_paths{app.editpathind+1,10};
                app.RDelaymsEditField.Value = app.raw_paths{app.editpathind+1,22};
            end
            drawnow update;
            app.ResetButton_2.Enable = "on";
        end

        % Button pushed function: ResetButton_2
        function resetPathAttr(app, event)
            exact_match = strcmp(app.raw_paths(1,:), app.AttributeDropDown_2.Value);
            idx = find(exact_match);            
            app.raw_paths{app.editpathind+1,idx} = app.raw_paths_reserve{app.editpathind+1,idx};
            app.AttributeValueEditField_2.Value = app.raw_paths{app.editpathind+1,idx};
            if idx == 5
                app.raw_paths{app.editpathind+1,21} = app.raw_paths_reserve{app.editpathind+1,21};
                app.ADelaymsEditField.Value = app.raw_paths{app.editpathind+1,21};
            elseif idx == 10
                app.raw_paths{app.editpathind+1,22} = app.raw_paths_reserve{app.editpathind+1,22};
                app.RDelaymsEditField.Value = app.raw_paths{app.editpathind+1,22};
            end
            drawnow update;
            app.ResetButton_2.Enable = "off";
        end

        % Button pushed function: UpdateButton
        function updateNodeAttr(app, event)
            exact_match = strcmp(app.raw_nodes(1,:), app.AttributeDropDown.Value);
            idx = find(exact_match);
            if length(idx) > 1
                if strcmp(app.NodeTypeEditField.Value,"M")
                    idx = idx(2);
                elseif strcmp(app.NodeTypeEditField.Value,"N")
                    idx = idx(1);
                elseif strcmp(app.NodeTypeEditField.Value,"NM")
                    %HERE: HOW TO HAVE NM WORK??
                    idx = idx(2);
                end
            end
            app.raw_nodes{app.editnodeind+1,idx} = app.AttributeValueEditField.Value;
            app.ResetButton.Enable = "on";
            drawnow update;
        end

        % Button pushed function: ResetButton
        function resetNodeAttr(app, event)
            exact_match = strcmp(app.raw_nodes(1,:), app.AttributeDropDown.Value);
            idx = find(exact_match);            
            if length(idx) > 1
                if strcmp(app.NodeTypeEditField.Value,"M")
                    idx = idx(2);
                elseif strcmp(app.NodeTypeEditField.Value,"N")
                    idx = idx(1);
                elseif strcmp(app.NodeTypeEditField.Value,"NM")
                    %HERE: HOW TO HAVE NM WORK??
                    idx = idx(2);
                end
            end
            app.raw_nodes{app.editnodeind+1,idx} = app.raw_nodes_reserve{app.editnodeind+1,idx};
            app.AttributeValueEditField.Value = app.raw_nodes{app.editnodeind+1,idx};
            drawnow update;
            app.ResetButton.Enable = "off";
        end

        % Value changed function: ExternalPulseCheckBox
        function nodeAPpulse(app, event)
            value = app.ExternalPulseCheckBox.Value;
            if value
                app.PulsePeriodEditField.Enable = "on";
            else
                app.PulsePeriodEditField.Enable = "off";
            end
        end

        % Button pushed function: SelectStartLocationButton
        function selectStartProbeLocation(app, event)
            app.SelectEndLocationButton.Enable = "off";
            app.SelectStartLocationButton.Enable = "off";
            app.SelectProbeButton.Enable = "off";
            app.SelectProbeButton_2.Enable = "off";
            app.newprobebool = true;
        end

        % Button pushed function: SelectEndLocationButton
        function selectEndProbeLocation(app, event)
            app.SelectEndLocationButton.Enable = "off";
            app.SelectStartLocationButton.Enable = "off";
            app.SelectProbeButton.Enable = "off";
            app.SelectProbeButton_2.Enable = "off";
            app.probe2bool = true;
        end

        % Button pushed function: SelectProbeButton
        function selectProbeDel(app, event)
            app.SelectEndLocationButton.Enable = "off";
            app.SelectStartLocationButton.Enable = "off";
            app.SelectProbeButton.Enable = "off";
            app.SelectProbeButton_2.Enable = "off";
            app.delprobebool = true;
        end

        % Button pushed function: SelectProbeButton_2
        function selectProbeEdit(app, event)
            app.SelectEndLocationButton.Enable = "off";
            app.SelectStartLocationButton.Enable = "off";
            app.SelectProbeButton.Enable = "off";
            app.SelectProbeButton_2.Enable = "off";
            app.editprobebool = true;
        end

        % Button pushed function: DeleteProbeButton
        function deleteProbe(app, event)
            % 1. Check for overlapping indices
            if app.editprobeind == app.delprobeind
                set(app.ProbeNumberEditField,'Value',app.ProbeNumberEditField.Placeholder);
                set(app.ProbeTypeEditField,'Value',app.ProbeTypeEditField.Placeholder);
                set(app.ParameterValueEditField,'Value',0.0);%app.AttributeValueEditField.Placeholder);
                app.SelectProbeButton_2.Enable = "on";
                app.ResetButton_3.Enable = "off";
                app.UpdateButton_3.Enable = "off";
                app.editprobeind = 0;
            end
            % 2. Delete the node physically from the plot
            app.Probe_pos(app.delprobeind,:) = [];
            app.probe_connections(app.delprobeind) = [];
            c = get(app.probe_pos_plot,'CData');
            c(app.delprobeind,:) = [];
            set(app.probe_pos_plot,'XData',app.Probe_pos(:,1),'YData',app.Probe_pos(:,2),'CData',c);
            % Delete the node from the arrays - requires finding the exact
            % node index as per the raw array
            true_index = app.raw_probes{app.delprobeind+1,3};
            partial1 =[app.raw_probes{2:end,3}].';
            [index1,~] = find(partial1 == true_index);
            index1 = index1+1;            
            app.raw_probes(index1,:) = [];
            app.raw_probes_reserve(index1,:) = [];
            app.delprobeind = 0;
            delete(app.tooltipObj)
            drawnow update;
            app.CancelButton_4.Enable = "off";
            app.DeleteProbeButton.Enable = "off";
        end

        % Button pushed function: CancelButton_4
        function cancelProbeDel(app, event)
            app.CancelButton_4.Enable = "off";
            app.DeleteProbeButton.Enable = "off";
            c = get(app.probe_pos_plot,'CData');
            color = [1 0 0];        
            [q, idx] = ismember(color,c,'rows');
            if q
                if(idx == app.editprobeind)
                    c(idx,:) = [0 0 1];
                else
                    c(idx,:) = [0 0 0];
                end
            end
            set(app.probe_pos_plot,'XData',app.Probe_pos(:,1),'YData',app.Probe_pos(:,2),'CData',c);
            app.delprobeind = 0;
        end

        % Button pushed function: CancelButton_3
        function cancelProbeSave(app, event)
            app.CancelButton_3.Enable = "off";
            app.SaveProbeButton.Enable = "off";
            
            % Removing the node from the graph
            c = get(app.probe_pos_plot,'CData');
            if (length(c) - length(app.Probe_pos)) == 2
                c(end-1:end,:) = [];
            elseif (length(c) - length(app.Probe_pos)) == 1
                c(end,:) = [];
            end            
            set(app.probe_pos_plot,'XData',app.Probe_pos(:,1),'YData',app.Probe_pos(:,2),'CData',c);
        end

        % Button pushed function: SaveProbeButton
        function saveProbe(app, event)
            app.CancelButton_3.Enable = "off";
            app.SaveProbeButton.Enable = "off";
            app.SelectEndLocationButton.Enable = "on";
            c =  get(app.probe_pos_plot,'CData');
            x_temp = get(app.probe_pos_plot,'XData');
            y_temp = get(app.probe_pos_plot,'YData');
            x1 = x_temp(end);
            y1 = y_temp(end);
            x2 = x_temp(end-1);
            y2 = y_temp(end-1);
            app.Probe_pos(end+1,:) = [x1 y1];
            app.Probe_pos(end+1,:) = [x2 y2];
            c(end,:) = [0 0 0];
            c(end-1,:) = [0 0 0];
            set(app.probe_pos_plot,'XData',app.Probe_pos(:,1),'YData',app.Probe_pos(:,2),'CData',c);
            
            n1 = app.unique_num(app.probe_connections);
            app.probe_connections(end+1)=n1;

            n2 = app.unique_num(app.probe_connections);
            app.probe_connections(end+1)=n2;
            line1 = {app.ProbeTypeDropDown.Value, n1, x1, y1, app.ZPositionEditField.Value, NaN, NaN, NaN, NaN, NaN}
            line2 = {app.ProbeTypeDropDown.Value, n2, x2, y2, app.ZPositionEditField.Value, NaN, NaN, NaN, NaN, NaN}
            app.raw_probes(end+1,:) = line1;
            app.raw_probes_reserve(end+1,:) = line1;
            app.raw_probes(end+1,:) = line2;
            app.raw_probes_reserve(end+1,:) = line2;
        end

        % Value changed function: ParameterDropDown
        function probeParameterChange(app, event)
            exact_match = strcmp(app.raw_probes(1,:), app.ParameterDropDown.Value);
            idx = find(exact_match);
            if ~app.editprobeind
                app.ParameterValueEditField.Value = 0;
            else                
                app.ParameterValueEditField.Value = app.raw_probes{app.editprobeind+1,idx};
            end
            drawnow update;
            
        end

        % Button pushed function: UpdateButton_3
        function updateProbeAttr(app, event)
            exact_match = strcmp(app.raw_probes(1,:), app.ParameterDropDown.Value);
            idx = find(exact_match);
            app.raw_probes{app.editprobeind+1,idx} = app.ParameterValueEditField.Value;
            app.probe_reset(idx)
            app.ResetButton_3.Enable = "on";
            drawnow update;
        end

        % Button pushed function: ResetButton_3
        function resetProbeAttr(app, event)
            exact_match = strcmp(app.raw_probes(1,:), app.ParameterDropDown.Value);
            idx = find(exact_match);            
            app.raw_probes{app.editprobeind+1,idx} = app.raw_probes_reserve{app.editprobeind+1,idx};
            app.ParameterValueEditField.Value = app.raw_probes{app.editprobeind+1,idx};
            app.probe_reset(idx)
            app.ResetButton_3.Enable = "off";
            
            drawnow update;
        end

        % Button pushed function: PlotRestitutionCurveButton
        function PlotRestCurve(app, event)
            % Following parameterization, a dynamic pacing protocol was
            % used to define the restitution properties of the HA models. It is
            % important to ensure that initial transients are no longer present
            % in the system. 
            % The pacing protocol used a preconditioning train
            % with progressive reduction in coupling interval (CI) in 10 ms
            % steps from 700 ms until the target CI was reached [31]. At that
            % point a steady train of 10 stimuli at the test CI were applied
            % and the APD of the tenth beat was plotted vs the ninth diastolic
            % interval (DI) [31].
            % [31] = Structural Heterogeneity Alone Is a Sufficient
            % Substrate for Dynamic Instability and Altered Restitution
            % 2010
            % Cited from Cardiac Electrical Modeling for Closed-loop
                % Validation of Implantable Devices 2019
            if app.ms_or_not
                paced = [700 650 600 550 500 450 440 430 420 410 400 390 380 370 360 350 340 330 320 310 300 290 280 270 260 250 240 230 220 210];
            else
                paced = [0.700 0.650 0.600 0.550 0.500 0.450 0.440 0.430 0.420 0.410 0.400 0.390 0.380 0.370 0.360 0.350 0.340 0.330 0.320 0.310 0.300 0.290 0.280 0.270 0.260 0.250 0.240 0.230 0.220 0.210];
            end
            
            % HERE: issue with accessing variables at chart initialisation,
            % consider whether the code outputs correct values
            % HERE: need to idealise the s parameterisation for the code
            
            cla(app.UIAxes3);
            
            disp('Plotting new trace')

            % Assigning the unchanging constants for the APD runs
            assignin("base","pulse_amp",app.raw_nodes{app.editnodeind+1,app.VTidx}+1)
            if strcmp('N',app.NodeTypeEditField.Value)
                assignin('base','params',[cell2mat(cat(2,app.raw_nodes(app.editnodeind+1,4:24),...
                    app.raw_nodes(app.editnodeind+1,end-3:end-2)))]);
                test = 'pacemakerN_V6dyn';
            elseif strcmp('M',app.NodeTypeEditField.Value)
                assignin('base','params',[cell2mat(app.raw_nodes(app.editnodeind+1,25:end-2))]);
                test = 'myocyteM_V4dyn';
            elseif strcmp('NM',app.NodeTypeEditField.Value)
                assignin('base','params',[cell2mat(app.raw_nodes(app.editnodeind+1,4:end-2)) 1 1]);
                test = 'myocyteNM_V4dyn';
            end
            results = restitution_run(test,paced);
            plot(app.UIAxes3,results(:,2),results(:,3),'go','tag','myAlteredRestCurve','DisplayName','Updated')
            
            disp('Plotting old trace')
            % Assigning the unchanging constants for the APD runs
            assignin("base","pulse_amp",app.raw_nodes_reserve{app.editnodeind+1,app.VTidx}+1)
            if strcmp('N',app.NodeTypeEditField.Value)
                assignin('base','params',[cell2mat(cat(2,app.raw_nodes_reserve(app.editnodeind+1,4:24),...
                    app.raw_nodes_reserve(app.editnodeind+1,end-3:end-2)))]);
            elseif strcmp('M',app.NodeTypeEditField.Value)
                assignin('base','params',[cell2mat(app.raw_nodes_reserve(app.editnodeind+1,25:end-2))]);
            elseif strcmp('NM',app.NodeTypeEditField.Value)
                assignin('base','params',[cell2mat(app.raw_nodes_reserve(app.editnodeind+1,4:end-2)) 1 1]);
            end
            results = restitution_run(test,paced);
            plot(app.UIAxes3,results(:,2),results(:,3),'rs','tag','myOgRestCurve','DisplayName','Baseline')

            legend(app.UIAxes3)
            drawnow update;
            app.SaveRestitutionPlotButton.Enable = "on";
        end

        % Button pushed function: SaveplotButton
        function exportAPData(app, event)
            % UI window allowing the operator to browse for and select a data file
            while (1)
                [fileName,filePath] = uiputfile({'*.png'},'Save AP plot');
                if isequal(fileName,0)
                    empty_answer = questdlg('Do you really want to cancel?', ...
                        'Cancel File Save','Yes','No','Yes');
                    switch empty_answer
                        case 'Yes'
                            return
                        case 'No'
                    end
                else
                    break
                end
            end
            % Check if the file name is correct for the output            
            sy = isempty(app.UIAxes2)
            if ~sy
                exportgraphics(app.UIAxes2,fullfile(filePath,fileName),'Resolution',300)
            end
        end

        % Button pushed function: ColourNodesButton
        function plotNodeAttr(app, event)
            %1. Get attribute data from storage exactly relevant to the
            %structure of node_pos_plot
            exact_match = strcmp(app.raw_nodes(1,:), app.NodeAttributeDropDown.Value);
            idx = find(exact_match);
            values = cell2mat(app.raw_nodes(2:end,idx));
            
            values(isnan(values))=40000;
            unique_vals = unique(values,'rows');
            values = changem(values,NaN,40000)
            unique_vals = changem(unique_vals,NaN,40000)
            
            % 2. colour grade in hsv OR jet, N/A as grey
            grey = [0.7 0.7 0.7];
            y = hsv(length(unique_vals)-1);
            %y = jet(length(unique_vals));
            colourgrade = app.node_pos_plot.CData;
            %jet(unique_vals)
            for i=1:size(values,1)
                if isnan(values(i,1))
                    colourgrade(i,:) = grey;
                else
                    % TO DEBUG: ERP, VT, VR have a duplicate for columns
                    % and will throw multiple values here
                    idx1 = find(values(i,1)==unique_vals);
                    colourgrade(i,:) = y(idx1,:);
                end
            end
            % 3. set the CData and update the plot
            app.node_pos_plot.CData = colourgrade;
            st = colorbar(app.UIAxes,'eastoutside')
            % ylabel by handle 
            
            clim(app.UIAxes,[min(unique_vals) max(unique_vals)])
            legend([app.node_pos_plot,app.probe_pos_plot],{'Node','Probe'});
            drawnow update;
        end

        % Button pushed function: ColourPathsButton
        function plotPathAttr(app, event)
            %1. Get attribute data from storage exactly relevant to the
            %structure of path_pos_plot
            exact_match = strcmp(app.raw_paths(1,:), app.PathAttributeDropDown.Value);
            idx = find(exact_match);
            values = cell2mat(app.raw_paths(2:end,idx));     
            unique_vals = unique(values,'rows');
            % 2. colour grade in whatever colormap
            
            %y = hsv(length(unique_vals));
            y = jet(length(unique_vals));
            % 3. loop through the paths and replot as the colours

            for n=1:size(app.path_connections,1)
                node1 = find(app.node_connections == app.path_connections(n,2));
                node2 = find(app.node_connections == app.path_connections(n,3));
                delete(app.path_plot(n))
                delete(findobj('tag',sprintf('path%i',n)))
                
                idx1 = find(values(n,1)==unique_vals);
                new_colour = y(idx1,:);
                app.path_plot(n)=line([app.Node_pos(node1,1),app.Node_pos(node2,1)],...
                    [app.Node_pos(node1,2),app.Node_pos(node2,2)],'LineWidth',1.5,'Color',new_colour,...
                    'Parent',app.UIAxes,'tag',sprintf('path%i',n),'ButtonDownFcn',@app.event_handle);
            end
            colorbar(app.UIAxes,'northoutside')
            clim(app.UIAxes,[min(unique_vals) max(unique_vals)])
            legend([app.node_pos_plot,app.probe_pos_plot],{'Node','Probe'});
            drawnow update;
        end

        % Callback function
        function updateN(app, event)
            %%%% DELETE THIS
        end

        % Button pushed function: ResetNodeColoursButton
        function resetNodeColours(app, event)
            % TO ADD: reset colours of the different selection modes
            app.node_pos_plot.CData = zeros(size(app.Node_pos,1),3);
            legend([app.node_pos_plot,app.probe_pos_plot],{'Node','Probe'});
            colorbar(app.UIAxes,'off')
            drawnow update;
        end

        % Callback function
        function ResetPathColours(app, event)
            %%%%% DELETE THIS
        end

        % Button pushed function: ResetPathColoursButton
        function resetPathColours(app, event)
            % TO ADD: reset colours of the different selection modes
            for n=1:size(app.path_connections,1)
                node1 = find(app.node_connections == app.path_connections(n,2));
                node2 = find(app.node_connections == app.path_connections(n,3));
                delete(app.path_plot(n))
                delete(findobj('tag',sprintf('path%i',n)))

                app.path_plot(n)=line([app.Node_pos(node1,1),app.Node_pos(node2,1)],...
                    [app.Node_pos(node1,2),app.Node_pos(node2,2)],'LineWidth',1.5,'Color',[0 0 0],...
                    'Parent',app.UIAxes,'tag',sprintf('path%i',n),'ButtonDownFcn',@app.event_handle);
            end
            colorbar(app.UIAxes,'off')
            legend([app.node_pos_plot,app.probe_pos_plot],{'Node','Probe'});
            drawnow update;
        end

        % Callback function: not associated with a component
        function selectProbeLocation(app, event)
            app.SelectStartLocationButton.Enable = "off";
            app.SelectProbeButton.Enable = "off";
            app.SelectProbeButton_2.Enable = "off";
            app.newprobebool = true;
        end

        % Value changed function: ImportCellMLDataCheckBox
        function ImportCellMLDataCheckBoxValueChanged(app, event)
            value = app.ImportCellMLDataCheckBox.Value;
            if value
                app.CellMLFileDataEditField.Enable = "on";
                app.ImportDataButton.Enable = "on";
            else
                app.CellMLFileDataEditField.Enable = "off";
                app.ImportDataButton.Enable = "off";
            end
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            % UI window allowing the operator to browse for and select a data file
            while (1)
                [fileName,filePath] = uigetfile({'*.csv';'*.xlsx'},'Load CellML File');
                if isequal(fileName,0)
                    empty_answer = questdlg('Do you really want to cancel?', ...
                        'Cancel File Load','Yes','No','Yes');
                    switch empty_answer
                        case 'Yes'
                            return
                        case 'No'
                    end
                else
                    break
                end
            end
            app.load_cellMLfile(filePath,fileName)
        end

        % Button pushed function: 
        % SaveParamsFilewithcurrentconfigurationButton
        function saveParamsFile(app, event)
            % MUST load in a .mat file before being able to use this
            %HERE: put the lookup in the startup function
            % function
            if isempty(app.path_lookup)||isempty(app.node_lookup)
                empty_answer = msgbox("An appropriate [cfg].mat file must be loaded before being able to use this function",...
                    "File Error","error");
                return
            end
            [node_rows,node_cols] = find(~cellfun(@isequaln,app.raw_nodes,app.raw_nodes_reserve));
            ppNode = zeros(length(node_rows),5);
            for i=1:length(node_rows)
                nodeNum = app.raw_nodes{node_rows(i),3};
                paramVal = app.raw_nodes{node_rows(i),node_cols(i)};
                paramNum = node_cols(i)-3;
                paramRange = cell2mat(app.raw_nodes_reserve(2:end,node_cols(i)));
                pRange_max = max(paramRange,[],"omitnan");
                pRange_min = min(paramRange,[],"omitnan");
                ppNode(i,:) = [nodeNum, paramNum, paramVal, pRange_max, pRange_min];
            end
            [path_rows,path_cols] = find(~cellfun(@isequaln,app.raw_paths(1:end,1:15),app.raw_paths_reserve(1:end,1:15)));
            ppPath = zeros(length(path_rows),5);
            for i=1:length(path_rows)
                pathNum = path_rows(i)-1;
                paramVal = app.raw_paths{path_rows(i),path_cols(i)};
                paramNum = path_cols(i)-4;
                paramRange = cell2mat(app.raw_paths_reserve(2:end,path_cols(i)));
                pRange_max = max(paramRange,[],"omitnan");
                pRange_min = min(paramRange,[],"omitnan");
                ppPath(i,:) = [pathNum, paramNum, paramVal, pRange_max, pRange_min];
            end

            nn=length(ppNode(:,1)); % The number of node parameters;
            np=length(ppPath(:,1)); % The number of path parameters;
            pp=zeros(1,nn+np);
            pRange=zeros(nn+np,2);
            % Look up the port number of node parameters
            pNode=zeros(1,nn);
            for i=1:nn
                Num=ppNode(i,1);
                Para=ppNode(i,2);
                n=Para+1;
                pNode(i)=app.node_lookup(Num,n);
                pp(i)=ppNode(i,3);
                pRange(i,1:2)=ppNode(i,4:5);
            end
            % Look up the port number of path parameters
            pPath=zeros(1,np);
            for i=1:np
                Num=ppPath(i,1);
                Para=ppPath(i,2);
                n=Para+2;    
                disp(Num)
                pPath(i)=app.path_lookup(Num,n);
                pp(i+nn)=ppPath(i,3);
                pRange(i+nn,1:2)=ppPath(i,4:5);
            end
            % save the new parameters
            [filename, location] = uiputfile({'*.mat'});
            save (fullfile(location,filename), 'ppNode','ppPath','pNode','pPath','pp','pRange');
        end

        % Button pushed function: SaveRestitutionPlotButton
        function exportRestData(app, event)
            % UI window allowing the operator to browse for and select a data file
            while (1)
                [fileName,filePath] = uiputfile({'*.png'},'Save Rest plot');
                if isequal(fileName,0)
                    empty_answer = questdlg('Do you really want to cancel?', ...
                        'Cancel File Save','Yes','No','Yes');
                    switch empty_answer
                        case 'Yes'
                            return
                        case 'No'
                    end
                else
                    break
                end
            end
            % Check if the file name is correct for the output            
            sy = isempty(app.UIAxes3)
            if ~sy
                exportgraphics(app.UIAxes3,fullfile(filePath,fileName),'Resolution',300)
            end
        end

        % Value changed function: UseExternaldataCheckBox
        function restDataCheck(app, event)
            value = app.UseExternaldataCheckBox.Value;
            if value
                app.ImportExternaldataButton.Enable = "on";
            else
                app.ImportExternaldataButton.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NodesTab = uitab(app.TabGroup);
            app.NodesTab.Title = 'Nodes';
            app.NodeCreationPanel = uipanel(app.NodesTab);
            app.NodeCreationPanel.Title = 'Node Creation';
            app.SelectLocationButton = uibutton(app.NodeCreationPanel, 'push');
            app.SelectLocationButton.ButtonPushedFcn = createCallbackFcn(app, @selectLocation, true);
            app.SelectLocationButton.Text = 'Select Location';
            app.NodeTypeDropDownLabel = uilabel(app.NodeCreationPanel);
            app.NodeTypeDropDownLabel.Text = 'Node Type';
            app.NodeTypeDropDown = uidropdown(app.NodeCreationPanel);
            app.NodeTypeDropDown.Items = {'N', 'M', 'NM'};
            app.NodeTypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @presetChange, true);
            app.NodeTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @presetChange, true);
            app.NodeTypeDropDown.ClickedFcn = createCallbackFcn(app, @presetChange, true);
            app.NodeTypeDropDown.Value = 'N';
            app.NodePresetsDropDownLabel = uilabel(app.NodeCreationPanel);
            app.NodePresetsDropDownLabel.Text = 'Node Presets';
            app.NodePresetsDropDown = uidropdown(app.NodeCreationPanel);
            app.NodePresetsDropDown.Items = {'N/A'};
            app.NodePresetsDropDown.Value = 'N/A';
            app.SaveNodeButton = uibutton(app.NodeCreationPanel, 'push');
            app.SaveNodeButton.ButtonPushedFcn = createCallbackFcn(app, @saveNode, true);
            app.SaveNodeButton.Enable = 'off';
            app.SaveNodeButton.Text = 'Save Node';
            app.CancelButton_2 = uibutton(app.NodeCreationPanel, 'push');
            app.CancelButton_2.ButtonPushedFcn = createCallbackFcn(app, @cancelNodeSave, true);
            app.CancelButton_2.Enable = 'off';
            app.CancelButton_2.Text = 'Cancel';
            app.NodeNameEditFieldLabel = uilabel(app.NodeCreationPanel);
            app.NodeNameEditFieldLabel.Text = 'Node Name';
            app.NodeNameEditField = uieditfield(app.NodeCreationPanel, 'text');
            app.NodeNameEditField.Placeholder = '[New Cell Name]';
            app.NodeDeletionPanel = uipanel(app.NodesTab);
            app.NodeDeletionPanel.Title = 'Node Deletion';
            app.SelectNodeButton = uibutton(app.NodeDeletionPanel, 'push');
            app.SelectNodeButton.ButtonPushedFcn = createCallbackFcn(app, @selectNodeDel, true);
            app.SelectNodeButton.Text = 'Select Node';
            app.CancelButton = uibutton(app.NodeDeletionPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelNodeDel, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Cancel';
            app.DeleteNodeButton = uibutton(app.NodeDeletionPanel, 'push');
            app.DeleteNodeButton.ButtonPushedFcn = createCallbackFcn(app, @deleteNode, true);
            app.DeleteNodeButton.Enable = 'off';
            app.DeleteNodeButton.Text = 'Delete Node';
            app.EditNodePanel = uipanel(app.NodesTab);
            app.EditNodePanel.Title = 'Edit Node';
            app.SelectNodeButton_2 = uibutton(app.EditNodePanel, 'push');
            app.SelectNodeButton_2.ButtonPushedFcn = createCallbackFcn(app, @selectNodeEdit, true);
            app.SelectNodeButton_2.Text = 'Select Node';
            app.AttributeDropDownLabel = uilabel(app.EditNodePanel);
            app.AttributeDropDownLabel.Text = 'Attribute';
            app.AttributeDropDown = uidropdown(app.EditNodePanel);
            app.AttributeDropDown.Items = {'N/A'};
            app.AttributeDropDown.ValueChangedFcn = createCallbackFcn(app, @editAttrChange, true);
            app.AttributeDropDown.Placeholder = 'N/A';
            app.AttributeDropDown.Value = 'N/A';
            app.UpdateButton = uibutton(app.EditNodePanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @updateNodeAttr, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.AttributeValueEditFieldLabel = uilabel(app.EditNodePanel);
            app.AttributeValueEditFieldLabel.Text = 'Attribute Value';
            app.AttributeValueEditField = uieditfield(app.EditNodePanel, 'numeric');
            app.AttributeValueEditField.ValueDisplayFormat = '%.3f';
            app.AttributeValueEditField.Placeholder = '0.0';
            app.ResetButton = uibutton(app.EditNodePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetNodeAttr, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.NodeNumberEditFieldLabel = uilabel(app.EditNodePanel);
            app.NodeNumberEditFieldLabel.Text = 'Node (Number)';
            app.NodeNumberEditField = uieditfield(app.EditNodePanel, 'text');
            app.NodeNumberEditField.Editable = 'off';
            app.NodeNumberEditField.Placeholder = '[No node selected]';
            app.NodeTypeEditFieldLabel = uilabel(app.EditNodePanel);
            app.NodeTypeEditFieldLabel.Text = 'Node Type';
            app.NodeTypeEditField = uieditfield(app.EditNodePanel, 'text');
            app.NodeTypeEditField.Editable = 'off';
            app.NodeTypeEditField.Placeholder = 'N/A';
            app.PathsTab = uitab(app.TabGroup);
            app.PathsTab.Title = 'Paths';
            app.EditPathPanel = uipanel(app.PathsTab);
            app.EditPathPanel.Title = 'Edit Path';
            app.Node1iLabel = uilabel(app.EditPathPanel);
            app.Node1iLabel.Text = 'Node 1 (i):';
            app.Node1iEditField = uieditfield(app.EditPathPanel, 'text');
            app.Node1iEditField.Editable = 'off';
            app.Node1iEditField.Placeholder = 'None';
            app.Node2jLabel = uilabel(app.EditPathPanel);
            app.Node2jLabel.Text = 'Node 2 (j):';
            app.Node2jEditField = uieditfield(app.EditPathPanel, 'text');
            app.Node2jEditField.Editable = 'off';
            app.Node2jEditField.Placeholder = 'None';
            app.SelectPathButton_2 = uibutton(app.EditPathPanel, 'push');
            app.SelectPathButton_2.ButtonPushedFcn = createCallbackFcn(app, @selectPathEdit, true);
            app.SelectPathButton_2.Text = 'Select Path';
            app.UpdateButton_2 = uibutton(app.EditPathPanel, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @updatePathAttr, true);
            app.UpdateButton_2.Enable = 'off';
            app.UpdateButton_2.Text = 'Update';
            app.AttributeDropDown_2Label = uilabel(app.EditPathPanel);
            app.AttributeDropDown_2Label.Text = 'Attribute';
            app.AttributeDropDown_2 = uidropdown(app.EditPathPanel);
            app.AttributeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @editPathChange, true);
            app.AttributeValueEditField_2Label = uilabel(app.EditPathPanel);
            app.AttributeValueEditField_2Label.Text = 'Attribute Value';
            app.AttributeValueEditField_2 = uieditfield(app.EditPathPanel, 'numeric');
            app.AttributeValueEditField_2.Placeholder = '0.0';
            app.ResetButton_2 = uibutton(app.EditPathPanel, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @resetPathAttr, true);
            app.ResetButton_2.Enable = 'off';
            app.ResetButton_2.Text = 'Reset';
            app.ADelaymsEditFieldLabel = uilabel(app.EditPathPanel);
            app.ADelaymsEditFieldLabel.Text = 'A Delay (ms)';
            app.ADelaymsEditField = uieditfield(app.EditPathPanel, 'numeric');
            app.ADelaymsEditField.Editable = 'off';
            app.ADelaymsEditField.Placeholder = '0.0';
            app.RDelaymsEditFieldLabel = uilabel(app.EditPathPanel);
            app.RDelaymsEditFieldLabel.Text = 'R Delay (ms)';
            app.RDelaymsEditField = uieditfield(app.EditPathPanel, 'numeric');
            app.RDelaymsEditField.Editable = 'off';
            app.RDelaymsEditField.Placeholder = '0.0';
            app.PathDeletionPanel = uipanel(app.PathsTab);
            app.PathDeletionPanel.Title = 'Path Deletion';
            app.SelectPathButton = uibutton(app.PathDeletionPanel, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @selectPathDel, true);
            app.SelectPathButton.Text = 'Select Path';
            app.DeletePathButton = uibutton(app.PathDeletionPanel, 'push');
            app.DeletePathButton.ButtonPushedFcn = createCallbackFcn(app, @deletePath, true);
            app.DeletePathButton.Enable = 'off';
            app.DeletePathButton.Text = 'Delete Path';
            app.CancelPathDeletionButton = uibutton(app.PathDeletionPanel, 'push');
            app.CancelPathDeletionButton.ButtonPushedFcn = createCallbackFcn(app, @cancelPathDel, true);
            app.CancelPathDeletionButton.Enable = 'off';
            app.CancelPathDeletionButton.Text = 'Cancel Path Deletion';
            app.PathCreationPanel = uipanel(app.PathsTab);
            app.PathCreationPanel.Title = 'Path Creation';
            app.Node1iLabel_2 = uilabel(app.PathCreationPanel);
            app.Node1iLabel_2.Text = 'Node 1 (i):';
            app.Node1iEditField_2 = uieditfield(app.PathCreationPanel, 'text');
            app.Node1iEditField_2.Editable = 'off';
            app.Node1iEditField_2.Placeholder = 'None';
            app.Node2jLabel_2 = uilabel(app.PathCreationPanel);
            app.Node2jLabel_2.Text = 'Node 2 (j):';
            app.Node2jEditField_2 = uieditfield(app.PathCreationPanel, 'text');
            app.Node2jEditField_2.Editable = 'off';
            app.Node2jEditField_2.Placeholder = 'None';
            app.SelectNode1Button = uibutton(app.PathCreationPanel, 'push');
            app.SelectNode1Button.ButtonPushedFcn = createCallbackFcn(app, @selectPathNode1, true);
            app.SelectNode1Button.Text = 'Select Node 1';
            app.SelectNode2Button = uibutton(app.PathCreationPanel, 'push');
            app.SelectNode2Button.ButtonPushedFcn = createCallbackFcn(app, @selectPathNode2, true);
            app.SelectNode2Button.Text = 'Select Node 2';
            app.SavePathButton = uibutton(app.PathCreationPanel, 'push');
            app.SavePathButton.ButtonPushedFcn = createCallbackFcn(app, @savePath, true);
            app.SavePathButton.Enable = 'off';
            app.SavePathButton.Text = 'Save Path';
            app.CancelPathCreationButton = uibutton(app.PathCreationPanel, 'push');
            app.CancelPathCreationButton.ButtonPushedFcn = createCallbackFcn(app, @cancelPathSave, true);
            app.CancelPathCreationButton.Enable = 'off';
            app.CancelPathCreationButton.Text = 'Cancel Path Creation';
            app.PathPresetDropDownLabel = uilabel(app.PathCreationPanel);
            app.PathPresetDropDownLabel.Text = 'Path Preset';
            app.PathPresetDropDown = uidropdown(app.PathCreationPanel);
            app.ProbesTab_2 = uitab(app.TabGroup);
            app.ProbesTab_2.Title = 'Probes';
            app.ProbeCreationPanel = uipanel(app.ProbesTab_2);
            app.ProbeCreationPanel.Title = 'Probe Creation';
            app.SelectStartLocationButton = uibutton(app.ProbeCreationPanel, 'push');
            app.SelectStartLocationButton.ButtonPushedFcn = createCallbackFcn(app, @selectStartProbeLocation, true);
            app.SelectStartLocationButton.Text = 'Select Start Location';
            app.ProbeTypeDropDownLabel = uilabel(app.ProbeCreationPanel);
            app.ProbeTypeDropDownLabel.Text = 'Probe Type';
            app.ProbeTypeDropDown = uidropdown(app.ProbeCreationPanel);
            app.ProbeTypeDropDown.Items = {'Aring', 'Atip', 'Vring', 'Vtip'};
            app.ProbeTypeDropDown.Value = 'Aring';
            app.SaveProbeButton = uibutton(app.ProbeCreationPanel, 'push');
            app.SaveProbeButton.ButtonPushedFcn = createCallbackFcn(app, @saveProbe, true);
            app.SaveProbeButton.Enable = 'off';
            app.SaveProbeButton.Text = 'Save Probe';
            app.CancelButton_3 = uibutton(app.ProbeCreationPanel, 'push');
            app.CancelButton_3.ButtonPushedFcn = createCallbackFcn(app, @cancelProbeSave, true);
            app.CancelButton_3.Enable = 'off';
            app.CancelButton_3.Text = 'Cancel';
            app.ZPositionEditFieldLabel = uilabel(app.ProbeCreationPanel);
            app.ZPositionEditFieldLabel.Text = 'Z Position';
            app.ZPositionEditField = uieditfield(app.ProbeCreationPanel, 'numeric');
            app.ZPositionEditField.Limits = [1 3];
            app.ZPositionEditField.RoundFractionalValues = 'on';
            app.ZPositionEditField.AllowEmpty = 'on';
            app.ZPositionEditField.Placeholder = '1.0';
            app.ZPositionEditField.Value = 1;
            app.SelectEndLocationButton = uibutton(app.ProbeCreationPanel, 'push');
            app.SelectEndLocationButton.ButtonPushedFcn = createCallbackFcn(app, @selectEndProbeLocation, true);
            app.SelectEndLocationButton.Text = 'Select End Location';
            app.ProbeDeletionPanel = uipanel(app.ProbesTab_2);
            app.ProbeDeletionPanel.Title = 'Probe Deletion';
            app.SelectProbeButton = uibutton(app.ProbeDeletionPanel, 'push');
            app.SelectProbeButton.ButtonPushedFcn = createCallbackFcn(app, @selectProbeDel, true);
            app.SelectProbeButton.Text = 'Select Probe';
            app.CancelButton_4 = uibutton(app.ProbeDeletionPanel, 'push');
            app.CancelButton_4.ButtonPushedFcn = createCallbackFcn(app, @cancelProbeDel, true);
            app.CancelButton_4.Enable = 'off';
            app.CancelButton_4.Text = 'Cancel';
            app.DeleteProbeButton = uibutton(app.ProbeDeletionPanel, 'push');
            app.DeleteProbeButton.ButtonPushedFcn = createCallbackFcn(app, @deleteProbe, true);
            app.DeleteProbeButton.Enable = 'off';
            app.DeleteProbeButton.Text = 'Delete Probe';
            app.EditProbePanel = uipanel(app.ProbesTab_2);
            app.EditProbePanel.Title = 'Edit Probe';
            app.SelectProbeButton_2 = uibutton(app.EditProbePanel, 'push');
            app.SelectProbeButton_2.ButtonPushedFcn = createCallbackFcn(app, @selectProbeEdit, true);
            app.SelectProbeButton_2.Text = 'Select Probe';
            app.ParameterLabel = uilabel(app.EditProbePanel);
            app.ParameterLabel.Text = 'Parameter';
            app.ParameterDropDown = uidropdown(app.EditProbePanel);
            app.ParameterDropDown.Items = {'x', 'y', 'z'};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @probeParameterChange, true);
            app.ParameterDropDown.Value = 'x';
            app.ParameterValueEditFieldLabel = uilabel(app.EditProbePanel);
            app.ParameterValueEditFieldLabel.Text = 'Parameter Value';
            app.ParameterValueEditField = uieditfield(app.EditProbePanel, 'numeric');
            app.ParameterValueEditField.Placeholder = '0.0';
            app.UpdateButton_3 = uibutton(app.EditProbePanel, 'push');
            app.UpdateButton_3.ButtonPushedFcn = createCallbackFcn(app, @updateProbeAttr, true);
            app.UpdateButton_3.Enable = 'off';
            app.UpdateButton_3.Text = 'Update';
            app.ResetButton_3 = uibutton(app.EditProbePanel, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @resetProbeAttr, true);
            app.ResetButton_3.Enable = 'off';
            app.ResetButton_3.Text = 'Reset';
            app.ProbeNumberEditFieldLabel = uilabel(app.EditProbePanel);
            app.ProbeNumberEditFieldLabel.Text = 'Probe (Number)';
            app.ProbeNumberEditField = uieditfield(app.EditProbePanel, 'text');
            app.ProbeNumberEditField.Editable = 'off';
            app.ProbeNumberEditField.Placeholder = '[No probe selected]';
            app.ProbeTypeEditFieldLabel = uilabel(app.EditProbePanel);
            app.ProbeTypeEditFieldLabel.Text = 'Probe Type';
            app.ProbeTypeEditField = uieditfield(app.EditProbePanel, 'text');
            app.ProbeTypeEditField.Editable = 'off';
            app.ProbeTypeEditField.Placeholder = 'N/A';
            app.ColouringTab = uitab(app.TabGroup);
            app.ColouringTab.Title = 'Colouring';
            app.NodeAttributeDropDownLabel = uilabel(app.ColouringTab);
            app.NodeAttributeDropDownLabel.Text = 'Node Attribute';
            app.NodeAttributeDropDown = uidropdown(app.ColouringTab);
            app.PathAttributeDropDownLabel = uilabel(app.ColouringTab);
            app.PathAttributeDropDownLabel.Text = 'Path Attribute';
            app.PathAttributeDropDown = uidropdown(app.ColouringTab);
            app.ColourNodesButton = uibutton(app.ColouringTab, 'push');
            app.ColourNodesButton.ButtonPushedFcn = createCallbackFcn(app, @plotNodeAttr, true);
            app.ColourNodesButton.Text = 'Colour Nodes';
            app.ColourPathsButton = uibutton(app.ColouringTab, 'push');
            app.ColourPathsButton.ButtonPushedFcn = createCallbackFcn(app, @plotPathAttr, true);
            app.ColourPathsButton.Text = 'Colour Paths';
            app.ResetNodeColoursButton = uibutton(app.ColouringTab, 'push');
            app.ResetNodeColoursButton.ButtonPushedFcn = createCallbackFcn(app, @resetNodeColours, true);
            app.ResetNodeColoursButton.Text = 'Reset Node Colours';
            app.ResetPathColoursButton = uibutton(app.ColouringTab, 'push');
            app.ResetPathColoursButton.ButtonPushedFcn = createCallbackFcn(app, @resetPathColours, true);
            app.ResetPathColoursButton.Text = 'Reset Path Colours';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.NetworkTab = uitab(app.TabGroup2);
            app.NetworkTab.Title = 'Network';
            app.UIAxes = uiaxes(app.NetworkTab);
            xlabel(app.UIAxes, 'X (mm)')
            ylabel(app.UIAxes, 'Y (mm)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 100];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ActionPotentialsTab = uitab(app.TabGroup2);
            app.ActionPotentialsTab.Title = 'Action Potentials';
            app.UIAxes2 = uiaxes(app.ActionPotentialsTab);
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Membrane Potential (mV)')
            zlabel(app.UIAxes2, 'Z')
            app.PulseandPlotPanel = uipanel(app.ActionPotentialsTab);
            app.PulseandPlotPanel.Title = 'Pulse and Plot';
            app.ExternalPulseCheckBox = uicheckbox(app.PulseandPlotPanel);
            app.ExternalPulseCheckBox.ValueChangedFcn = createCallbackFcn(app, @nodeAPpulse, true);
            app.ExternalPulseCheckBox.Enable = 'off';
            app.ExternalPulseCheckBox.Text = 'External Pulse';
            app.PulsePeriodEditFieldLabel = uilabel(app.PulseandPlotPanel);
            app.PulsePeriodEditFieldLabel.Enable = 'off';
            app.PulsePeriodEditFieldLabel.Text = 'Pulse Period';
            app.PulsePeriodEditField = uieditfield(app.PulseandPlotPanel, 'numeric');
            app.PulsePeriodEditField.Limits = [0.05 Inf];
            app.PulsePeriodEditField.ValueDisplayFormat = '%.8f';
            app.PulsePeriodEditField.Enable = 'off';
            app.PulsePeriodEditField.Placeholder = '1';
            app.PulsePeriodEditField.Value = 1;
            app.PlotAPButton = uibutton(app.PulseandPlotPanel, 'push');
            app.PlotAPButton.ButtonPushedFcn = createCallbackFcn(app, @plotAPtrace, true);
            app.PlotAPButton.Enable = 'off';
            app.PlotAPButton.Text = 'Plot AP';
            app.ImportCellMLDataCheckBox = uicheckbox(app.ActionPotentialsTab);
            app.ImportCellMLDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImportCellMLDataCheckBoxValueChanged, true);
            app.ImportCellMLDataCheckBox.Text = 'Import CellML Data';
            app.CellMLFileDataEditFieldLabel = uilabel(app.ActionPotentialsTab);
            app.CellMLFileDataEditFieldLabel.Enable = 'off';
            app.CellMLFileDataEditFieldLabel.Text = 'CellML File Data';
            app.CellMLFileDataEditField = uieditfield(app.ActionPotentialsTab, 'text');
            app.CellMLFileDataEditField.Enable = 'off';
            app.CellMLFileDataEditField.Placeholder = 'CellAP.csv';
            app.ImportDataButton = uibutton(app.ActionPotentialsTab, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Enable = 'off';
            app.ImportDataButton.Text = 'Import Data';
            app.SaveplotButton = uibutton(app.ActionPotentialsTab, 'push');
            app.SaveplotButton.ButtonPushedFcn = createCallbackFcn(app, @exportAPData, true);
            app.SaveplotButton.Text = 'Save plot';
            app.RestitutionCurvesTab = uitab(app.TabGroup2);
            app.RestitutionCurvesTab.Title = 'Restitution Curves';
            app.UIAxes3 = uiaxes(app.RestitutionCurvesTab);
            xlabel(app.UIAxes3, 'DI (ms)')
            ylabel(app.UIAxes3, 'APD (ms)')
            app.PlotRestitutionCurveButton = uibutton(app.RestitutionCurvesTab, 'push');
            app.PlotRestitutionCurveButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRestCurve, true);
            app.PlotRestitutionCurveButton.Enable = 'off';
            app.PlotRestitutionCurveButton.Text = 'Plot Restitution Curve';
            app.SaveRestitutionPlotButton = uibutton(app.RestitutionCurvesTab, 'push');
            app.SaveRestitutionPlotButton.ButtonPushedFcn = createCallbackFcn(app, @exportRestData, true);
            app.SaveRestitutionPlotButton.Enable = 'off';
            app.SaveRestitutionPlotButton.Text = 'Save Restitution Plot';
            app.ImportExternaldataButton = uibutton(app.RestitutionCurvesTab, 'push');
            app.ImportExternaldataButton.Enable = 'off';
            app.ImportExternaldataButton.Text = 'Import External data';
            app.UseExternaldataCheckBox = uicheckbox(app.RestitutionCurvesTab);
            app.UseExternaldataCheckBox.ValueChangedFcn = createCallbackFcn(app, @restDataCheck, true);
            app.UseExternaldataCheckBox.Enable = 'off';
            app.UseExternaldataCheckBox.Text = 'Use External data';
            app.LoadandSavePanel = uipanel(app.UIFigure);
            app.LoadandSavePanel.Title = 'Load and Save';
            app.SelectNetworkConfigFileButton = uibutton(app.LoadandSavePanel, 'push');
            app.SelectNetworkConfigFileButton.ButtonPushedFcn = createCallbackFcn(app, @selectConfigFile, true);
            app.SelectNetworkConfigFileButton.Text = 'Select Network Config File';
            app.CurrentConfigFileEditFieldLabel = uilabel(app.LoadandSavePanel);
            app.CurrentConfigFileEditFieldLabel.Text = 'Current Config File';
            app.CurrentConfigFileEditField = uieditfield(app.LoadandSavePanel, 'text');
            app.CurrentConfigFileEditField.Editable = 'off';
            app.CurrentConfigFileEditField.Placeholder = '[No current file]';
            app.SaveNetworkandQuitButton = uibutton(app.LoadandSavePanel, 'push');
            app.SaveNetworkandQuitButton.ButtonPushedFcn = createCallbackFcn(app, @saveNetwork, true);
            app.SaveNetworkandQuitButton.Text = 'Save Network and Quit';
            app.SaveParamsFilewithcurrentconfigurationButton = uibutton(app.LoadandSavePanel, 'push');
            app.SaveParamsFilewithcurrentconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @saveParamsFile, true);
            app.SaveParamsFilewithcurrentconfigurationButton.Enable = 'off';
            app.SaveParamsFilewithcurrentconfigurationButton.Text = 'Save Params File with current configuration';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorial_edit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end